classdef Instrucciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InstruccionesUIFigure           matlab.ui.Figure
        IdiomaLanguageMenu              matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        EspaolMenu                      matlab.ui.container.Menu
        ASPECTOSATENERENCUENTAPanel     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PrimerospasosTab                matlab.ui.container.Tab
        InicioPanel                     matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        Image8                          matlab.ui.control.Image
        LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        AlsubirarchivosconextensintxtPanel  matlab.ui.container.Panel
        Image_4                         matlab.ui.control.Image
        Label_6                         matlab.ui.control.Label
        AloprimirestebLabel             matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Label_3                         matlab.ui.control.Label
        Tree                            matlab.ui.container.Tree
        InstructivoPasosNode            matlab.ui.container.TreeNode
        PasounoNode                     matlab.ui.container.TreeNode
        IntroduccinNode                 matlab.ui.container.TreeNode
        ParmetrosNode                   matlab.ui.container.TreeNode
        FormatosNode                    matlab.ui.container.TreeNode
        PasodosNode                     matlab.ui.container.TreeNode
        PasotresNode                    matlab.ui.container.TreeNode
        ConvencionesNode                matlab.ui.container.TreeNode
        InterrogacinNode                matlab.ui.container.TreeNode
        AlsubirarchivosconextensinxlsxyxlsPanel  matlab.ui.container.Panel
        Label2                          matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        FormatossubirarchivosTab        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        txtformatoquenosedetectamesesTab  matlab.ui.container.Tab
        Image2_5                        matlab.ui.control.Image
        txtformatoquesedetectamesesTab  matlab.ui.container.Tab
        Image2_3                        matlab.ui.control.Image
        ConvencionesListBox_2Label      matlab.ui.control.Label
        ConvencionesListBox_2           matlab.ui.control.ListBox
        xlsxformatoquenosedetectamesesTab  matlab.ui.container.Tab
        Image2_4                        matlab.ui.control.Image
        xlsxformatoquesedetectamesesTab  matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        ConvencionesListBoxLabel        matlab.ui.control.Label
        ConvencionesListBox             matlab.ui.control.ListBox
        Panel                           matlab.ui.container.Panel
    end

%     AUTHORS
%
%     [1] John Jairo Guerrero Moreno
%     [2] Diego Julián Rodríguez Patarroyo
%     [3] Julián Andrés Salamanca Bernal
%
%     Universidad Distrital Francisco José de Caldas
%
%    Facultad de Ingeniería
%    Proyecto Curricular Ingeniería Eléctrica
%    [1,2] Laboratorio de Investigación de Fuentes Alternativas de Energía - LIFAE
%    [3] Grupo de Física e Informática - FISINFOR
%    Emails:
%    [1] jjguerrerom@correo.udistrital.edu.co,
%    [2] djrodriguezp@udistrital.edu.co,
%    [3] jasalamanca@udistrital.edu.co
%    December 1st, 2020


%COPYRIGHT
%
%     This file is part of SCWindDesign.
%     SCWindDesign is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
%
%     SCWindDesign is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
%
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.

    properties (Access = private)
        Menuapp % objeto para crear instancia entre el Menu y esta aplicación
        idioma
        titulo = "inicio";
    end
    
    methods (Access = private)
        
        function guardaridiomas(app,idioma) 
            app.ASPECTOSATENERENCUENTAPanel.Title = buscador(app, idioma, 'aspectos');
            app.PrimerospasosTab.Title = buscador(app, idioma, 'primerospasos');
            app.FormatossubirarchivosTab.Title = buscador(app, idioma, 'formatosubir');
            app.InstructivoPasosNode.Text = "»»" + buscador(app, app.idioma, 'ins');
            app.PasounoNode.Text =  buscador(app, idioma, 'puno');
            app.PasodosNode.Text = "»»" + buscador(app, idioma, 'pdos' );
            app.PasotresNode.Text = "»»" + buscador(app, idioma, 'ptres');
            app.IntroduccinNode.Text = "»»"  + buscador(app, idioma, 'introduccion');
            app.ParmetrosNode.Text = "»»" + buscador(app, idioma, 'pparametros');
            app.FormatosNode.Text =  "»»"  + buscador(app, idioma, 'pformatos');
            app.ConvencionesNode.Text =  buscador(app, idioma, 'simbolos');
            app.InterrogacinNode.Text = "»»" + buscador(app, idioma, 'interrogacion');
            app.txtformatoquenosedetectamesesTab.Title = buscador(app, idioma, 'txtno');
            app.txtformatoquesedetectamesesTab.Title = buscador(app, idioma , 'txtme');
            app.xlsxformatoquenosedetectamesesTab.Title = buscador(app , idioma, 'xlsxno');
            app.xlsxformatoquesedetectamesesTab.Title = buscador(app, idioma, 'xlsxme');
            app.ConvencionesListBoxLabel.Text = buscador(app, idioma, 'convenciones');
            app.ConvencionesListBox.Items = buscador(app, idioma, 'meses');
            app.ConvencionesListBox_2Label.Text = buscador(app, idioma, 'convenciones');
            app.ConvencionesListBox_2.Items = buscador(app, idioma, 'meses');
            
            switch app.titulo
                case 'inicio'
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'inicio');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasoinicio');
                case 'uno'
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'uno');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasouno');
                case 'dos'  
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'dos');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasodos');
                case 'tres'
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'tres');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasotres');
                case 'formatos'
                    app.Label_5.Text = buscador(app, app.idioma, 'formato');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasouno');
                case 'simbolo'
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'question');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'simbolos');
            end
            
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Text = buscador(app, idioma, 'extensiones');
            app.AlsubirarchivosconextensintxtPanel.Title = buscador(app, idioma, 'paneltxt');
            app.Label_3.Text = buscador(app, idioma, 'texttxt');
            app.AlsubirarchivosconextensinxlsxyxlsPanel.Title = buscador(app, idioma, 'panelxlsx');
            app.Label2.Text = buscador(app, idioma, 'textxlsx');
            ...app.Label_2.Text = buscador(app, idioma, 'nota');
            ...app.Label.Text = buscador(app, idioma, 'advertencia');
        end
        
        
        function  Idioma = buscador(app, idioma, texto)
                
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, menuapp, idioma)
            
            app.Menuapp = menuapp;
            app.idioma = idioma;
            app.ASPECTOSATENERENCUENTAPanel.Visible = "on";
            if idioma == "Español"
                app.EspaolMenu.Checked = "on";
                app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end
            expand(app.Tree);
            expand(app.PasounoNode);
            app.Tree.SelectedNodes = app.InstructivoPasosNode;
            app.FormatossubirarchivosTab.Parent = [];
            app.Panel.Visible = "off";


        end

        % Close request function: InstruccionesUIFigure
        function InstruccionesUIFigureCloseRequest(app, event)
            app.Menuapp.InstruccionesButton.Enable = "on";
            delete(app)  
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.NodeData
                case 1
                    app.Label_5.Visible = "off"
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'inicio');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasoinicio');
                    app.AloprimirestebLabel.Visible = "on";
                    app.Image6.ImageSource = 'instrucciones1.svg';
                    app.Image6.Visible = "on";
                    app.titulo = "inicio";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image8.Visible = "off";
                    
                case 3
                    app.Label_5.Visible = "off";
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'uno');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasouno');
                    app.AloprimirestebLabel.Visible = "on";
                    app.Image6.ImageSource = 'subir.svg';
                    app.Image6.Visible = "on";
                    app.titulo = "uno";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image8.Visible = "off";
                case 4
                    app.Label_5.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "on";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "on";
                    app.AloprimirestebLabel.Visible = "off";
                    app.Image6.Visible = "off";
                    app.titulo = "parametros";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "on";
                    app.Image8.Visible = "off";

                case 5
                    app.Label_5.Text = buscador(app, app.idioma, 'formato');
                    app.Label_5.Visible = "on";
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasouno');
                    app.AloprimirestebLabel.Visible = "off";
                    app.titulo = "formatos";
                    app.Image6.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.FormatossubirarchivosTab.Parent = app.TabGroup;
                    app.Panel.Visible = "off";
                    app.Image8.Visible = "off";
                case 6
                    app.Label_5.Visible = "off";
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'dos');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasodos');
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "dos";
                    app.Image6.ImageSource = 'conf.svg';
                    app.Image6.Visible = "on";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image8.Visible = "off";
                case 7
                    app.Label_5.Visible = "off";
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'tres');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'pasotres');
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "tres";
                    app.Image6.ImageSource = 'start.svg';
                    app.Image6.Visible = "on";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    app.Image8.Visible = "off";
                    
                case 9
                    app.Image8.Visible = "on";
                    app.Label_5.Visible = "off";
                    app.AloprimirestebLabel.Text = buscador(app, app.idioma, 'question');
                    app.InterrogacinNode.Text = "»»" + buscador(app, app.idioma, 'interrogacion');
                    app.InicioPanel.Title = buscador(app, app.idioma, 'simbolos');
                    app.AloprimirestebLabel.Visible = "on";
                    app.titulo = "simbolo";
                    app.Image6.Visible = "off";
                    app.AlsubirarchivosconextensintxtPanel.Visible = "off";
                    app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = "off";
                    app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = "off";
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InstruccionesUIFigure = uifigure('Visible', 'off');
            app.InstruccionesUIFigure.Name = 'Instrucciones';
            app.InstruccionesUIFigure.Icon = 'icono.png';
            app.InstruccionesUIFigure.CloseRequestFcn = createCallbackFcn(app, @InstruccionesUIFigureCloseRequest, true);
            app.IdiomaLanguageMenu = uimenu(app.InstruccionesUIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Separator = 'on';
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Separator = 'on';
            app.EspaolMenu.Text = 'Español';
            app.ASPECTOSATENERENCUENTAPanel = uipanel(app.InstruccionesUIFigure);
            app.ASPECTOSATENERENCUENTAPanel.Title = 'ASPECTOS A TENER EN CUENTA';
            app.TabGroup = uitabgroup(app.ASPECTOSATENERENCUENTAPanel);
            app.PrimerospasosTab = uitab(app.TabGroup);
            app.PrimerospasosTab.Title = 'Primeros pasos';
            app.InicioPanel = uipanel(app.PrimerospasosTab);
            app.InicioPanel.Title = 'Inicio';
            app.Panel_2 = uipanel(app.InicioPanel);
            app.Image8 = uiimage(app.Panel_2);
            app.Image8.Visible = 'off';
            app.Image8.ImageSource = 'help.svg';
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel = uilabel(app.Panel_2);
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Visible = 'off';
            app.LasextensionesqueelaplicativomanejasontxtxlsxxlsLabel.Text = 'Las extensiones de archivo que el aplicativo maneja son:  ''''.txt''''     ''''.xlsx ''''     ".xls" .';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = {'Nota: Uno de los procesos en el análisis del diseño eólico es la '; '         distribución del viento, donde se ve el comportamiento de los '; 'datos del  viento a traves  de Histogramas  y sus ajustes de '; 'distribución. '; ''; 'El aplicativo por defecto permite realizar el histograma usando '; 'todos los datos. Si desea hacerlo para algún mes especifico,'; 'es necesario que configure el formato del archivo para las extensiones'; 'soportadas como se indica en la  pestaña "Formatos"'; ''};
            app.AlsubirarchivosconextensintxtPanel = uipanel(app.Panel_2);
            app.AlsubirarchivosconextensintxtPanel.Title = 'Al subir archivos con extensión .txt';
            app.AlsubirarchivosconextensintxtPanel.Visible = 'off';
            app.Image_4 = uiimage(app.AlsubirarchivosconextensintxtPanel);
            app.Image_4.ImageSource = 'puntos.png';
            app.Label_6 = uilabel(app.AlsubirarchivosconextensintxtPanel);
            app.Label_6.Text = {'1. Asegurese que el archivo fue exportado con un delimitador'; ' de espacios.'; ''; '2. Asegurese que los decimales esten señalados con punto (.).'; ''};
            app.AloprimirestebLabel = uilabel(app.Panel_2);
            app.AloprimirestebLabel.Text = {'Bienvenido usuario(a), este instructivo tiene como finalidad '; 'enseñar los primeros pasos para subir los archivos y configurarlos.'; 'Con el motivo de que el archivo una vez configurado, se pueda proceder con el '; 'proceso de etapas para el análisis eólico.'};
            app.Image6 = uiimage(app.Panel_2);
            app.Image6.ImageSource = 'instrucciones1.svg';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Visible = 'off';
            app.Tree = uitree(app.PrimerospasosTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InstructivoPasosNode = uitreenode(app.Tree);
            app.InstructivoPasosNode.NodeData = 1;
            app.InstructivoPasosNode.Icon = 'instrucciones1.svg';
            app.InstructivoPasosNode.Text = 'Instructivo-Pasos';
            app.PasounoNode = uitreenode(app.InstructivoPasosNode);
            app.PasounoNode.NodeData = 2;
            app.PasounoNode.Text = '1.Paso uno';
            app.IntroduccinNode = uitreenode(app.PasounoNode);
            app.IntroduccinNode.NodeData = 3;
            app.IntroduccinNode.Text = '1.1.Introducción';
            app.ParmetrosNode = uitreenode(app.PasounoNode);
            app.ParmetrosNode.NodeData = 4;
            app.ParmetrosNode.Text = '1.2.Parámetros';
            app.FormatosNode = uitreenode(app.PasounoNode);
            app.FormatosNode.NodeData = 5;
            app.FormatosNode.Text = '1.3.Formatos';
            app.PasodosNode = uitreenode(app.InstructivoPasosNode);
            app.PasodosNode.NodeData = 6;
            app.PasodosNode.Text = '2.Paso dos';
            app.PasotresNode = uitreenode(app.InstructivoPasosNode);
            app.PasotresNode.NodeData = 7;
            app.PasotresNode.Text = '3.Paso tres';
            app.ConvencionesNode = uitreenode(app.Tree);
            app.ConvencionesNode.NodeData = 8;
            app.ConvencionesNode.Text = 'Convenciones';
            app.InterrogacinNode = uitreenode(app.ConvencionesNode);
            app.InterrogacinNode.NodeData = 9;
            app.InterrogacinNode.Text = 'Interrogación';
            app.AlsubirarchivosconextensinxlsxyxlsPanel = uipanel(app.PrimerospasosTab);
            app.AlsubirarchivosconextensinxlsxyxlsPanel.Title = 'Al subir archivos con extensión .xlsx y .xls';
            app.AlsubirarchivosconextensinxlsxyxlsPanel.Visible = 'off';
            app.Label2 = uilabel(app.AlsubirarchivosconextensinxlsxyxlsPanel);
            app.Label2.Text = {'1. Asegurese que en el excel, la opción "Inicio", en el cuadro del '; 'Formato de "Número" de los datos estén en "Número" .'; ''; '2. Asegurese que en el excel,  para los decimales sea coma (,) y '; 'no punto (.)'; ''};
            app.Image_3 = uiimage(app.AlsubirarchivosconextensinxlsxyxlsPanel);
            app.Image_3.ImageSource = 'comas.png';
            app.Image7 = uiimage(app.PrimerospasosTab);
            app.Image7.ImageSource = 'iconogrande.svg';
            app.FormatossubirarchivosTab = uitab(app.TabGroup);
            app.FormatossubirarchivosTab.Title = 'Formatos - subir archivos';
            app.TabGroup2 = uitabgroup(app.FormatossubirarchivosTab);
            app.txtformatoquenosedetectamesesTab = uitab(app.TabGroup2);
            app.txtformatoquenosedetectamesesTab.Title = '.txt formato que no se detecta meses';
            app.Image2_5 = uiimage(app.txtformatoquenosedetectamesesTab);
            app.Image2_5.ImageSource = 'txtsinformato.png';
            app.txtformatoquesedetectamesesTab = uitab(app.TabGroup2);
            app.txtformatoquesedetectamesesTab.Title = '.txt formato que se detecta meses';
            app.Image2_3 = uiimage(app.txtformatoquesedetectamesesTab);
            app.Image2_3.ImageSource = 'txtconformato.png';
            app.ConvencionesListBox_2Label = uilabel(app.txtformatoquesedetectamesesTab);
            app.ConvencionesListBox_2Label.Text = 'Convenciones';
            app.ConvencionesListBox_2 = uilistbox(app.txtformatoquesedetectamesesTab);
            app.ConvencionesListBox_2.Items = {'Enero : ene', 'Febrero : feb', 'Marzo : mar', 'Abril : abr', 'Mayo : may', 'Junio : jun', 'Julio : jul', 'Agosto : ago', 'Septiembre :  sep', 'Octubre : oct', 'Noviembre : nov', 'Diciembre : dic'};
            app.ConvencionesListBox_2.Value = 'Enero : ene';
            app.xlsxformatoquenosedetectamesesTab = uitab(app.TabGroup2);
            app.xlsxformatoquenosedetectamesesTab.Title = '.xlsx formato que no se detecta meses';
            app.Image2_4 = uiimage(app.xlsxformatoquenosedetectamesesTab);
            app.Image2_4.ImageSource = 'xlsxsinformato.png';
            app.xlsxformatoquesedetectamesesTab = uitab(app.TabGroup2);
            app.xlsxformatoquesedetectamesesTab.Title = '.xlsx formato  que se detecta meses';
            app.Image2 = uiimage(app.xlsxformatoquesedetectamesesTab);
            app.Image2.ImageSource = 'xlsxconformato.png';
            app.ConvencionesListBoxLabel = uilabel(app.xlsxformatoquesedetectamesesTab);
            app.ConvencionesListBoxLabel.Text = 'Convenciones';
            app.ConvencionesListBox = uilistbox(app.xlsxformatoquesedetectamesesTab);
            app.ConvencionesListBox.Items = {'Enero : ene', 'Febrero : feb', 'Marzo : mar', 'Abril : abr', 'Mayo : may', 'Junio : jun', 'Julio : jul', 'Agosto : ago', 'Septiembre :  sep', 'Octubre : oct', 'Noviembre : nov', 'Diciembre : dic'};
            app.ConvencionesListBox.Value = 'Enero : ene';
            app.Panel = uipanel(app.ASPECTOSATENERENCUENTAPanel);
            % Show the figure after all components are created
            app.InstruccionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Instrucciones(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InstruccionesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InstruccionesUIFigure)
        end
    end
end