classdef Instructivo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InstructivodelprocesoUIFigure  matlab.ui.Figure
        IdiomaLanguageMenu             matlab.ui.container.Menu
        EnglishMenu                    matlab.ui.container.Menu
        EspaolMenu                     matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        Tree                           matlab.ui.container.Tree
        InformacinNode                 matlab.ui.container.TreeNode
        MetodologadelprocesoNode       matlab.ui.container.TreeNode
        Paso1Node                      matlab.ui.container.TreeNode
        Paso2Node                      matlab.ui.container.TreeNode
        Paso3Node                      matlab.ui.container.TreeNode
        Paso4Node                      matlab.ui.container.TreeNode
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Image8                         matlab.ui.control.Image
        ElaplicativofuediseadopararealizarelprocesopasoapasoLabel  matlab.ui.control.Label
        Image8_2                       matlab.ui.control.Image
        Image8_3                       matlab.ui.control.Image
        Image8_4                       matlab.ui.control.Image
        Paso1Tab                       matlab.ui.container.Tab
        Image9                         matlab.ui.control.Image
        AlobtenerlavelocidadmediadelosdatosindicarsiesviableLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Paso2Tab                       matlab.ui.container.Tab
        Image9_2                       matlab.ui.control.Image
        LarosadevientosconsisteenundiagramapolarenelcualsedefinenLabel  matlab.ui.control.Label
        ConlarosadevientossepuedeubicarlosaerogeneradoresLabel  matlab.ui.control.Label
        Paso3Tab                       matlab.ui.container.Tab
        Image10                        matlab.ui.control.Image
        LosparmetrosdelasvariablesdisponiblesLabel  matlab.ui.control.Label
        LosparmetrosdelasvariablesdisponiblesLabel_2  matlab.ui.control.Label
        Paso4Tab                       matlab.ui.container.Tab
        Image11                        matlab.ui.control.Image
        ElaplicativoLabel              matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        Panel_2                        matlab.ui.container.Panel
    end

%     AUTHORS
%
%     [1] John Jairo Guerrero Moreno
%     [2] Diego Julián Rodríguez Patarroyo
%     [3] Julián Andrés Salamanca Bernal
%
%     Universidad Distrital Francisco José de Caldas
%
%    Facultad de Ingeniería
%    Proyecto Curricular Ingeniería Eléctrica
%    [1,2] Laboratorio de Investigación de Fuentes Alternativas de Energía - LIFAE
%    [3] Grupo de Física e Informática - FISINFOR
%    Emails:
%    [1] jjguerrerom@correo.udistrital.edu.co,
%    [2] djrodriguezp@udistrital.edu.co,
%    [3] jasalamanca@udistrital.edu.co
%    December 1st, 2020


%COPYRIGHT
%
%     This file is part of SCWindDesign.
%     SCWindDesign is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
%
%     SCWindDesign is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
%
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    
    properties (Access = private)
        Procesoapp;
        idioma;
        titulo;
    end
    
    methods (Access = public)
        
        function guardaridiomas(app, idioma) 
            app.InformacinNode.Text = "»»" + buscador(app, idioma, 'pinfo');
            app.MetodologadelprocesoNode.Text = buscador(app, idioma, 'pmetodo');
            app.Paso1Node.Text = "»»" + buscador(app , idioma, 'pone');
            app.Paso2Node.Text = "»»" + buscador(app, idioma, 'ptwo');
            app.Paso3Node.Text = "»»" + buscador(app, idioma, 'pthree');
            app.Paso4Node.Text = "»»" + buscador(app, idioma, 'pfour');
            app.LarosadevientosconsisteenundiagramapolarenelcualsedefinenLabel.Text = buscador(app, idioma, 'segundopaso');
            app.ConlarosadevientossepuedeubicarlosaerogeneradoresLabel.Text = buscador(app, idioma, 'segundopaso2');
            app.ElaplicativofuediseadopararealizarelprocesopasoapasoLabel.Text = buscador(app, idioma, 'iniciopaso');
            app.AlobtenerlavelocidadmediadelosdatosindicarsiesviableLabel.Text = buscador(app, idioma, 'primerpaso');
            app.TextArea.Value = buscador(app, idioma, 'primerpaso2');
            app.ElaplicativoLabel.Text = buscador(app, idioma, 'cuartopaso');
            app.LosparmetrosdelasvariablesdisponiblesLabel.Text = buscador(app, idioma, 'tercerpaso');
            app.LosparmetrosdelasvariablesdisponiblesLabel_2.Text = buscador(app , idioma, 'tercerpaso2');
        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, procesoapp, idioma)
            app.Procesoapp = procesoapp;
            app.idioma = idioma;
            if idioma == "Español"
                app.EspaolMenu.Checked = "on";
                app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end
            app.Tree.SelectedNodes = app.InformacinNode;
            expand(app.MetodologadelprocesoNode);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.NodeData
                case 1
                    app.TabGroup.SelectedTab = app.Tab;
                    app.Panel_2.Title = "";
                case 2
                    
                case 3
                    app.TabGroup.SelectedTab = app.Paso1Tab;
                    app.Panel_2.Title = buscador(app, app.idioma, 'pone');
                case 4
                    app.TabGroup.SelectedTab = app.Paso2Tab;
                    app.Panel_2.Title = buscador(app, app.idioma, 'ptwo');
                case 5
                    app.TabGroup.SelectedTab = app.Paso3Tab;
                    app.Panel_2.Title = buscador(app, app.idioma, 'pthree');
                case 6
                    app.TabGroup.SelectedTab = app.Paso4Tab;
                    app.Panel_2.Title = buscador(app, app.idioma, 'pfour');
            end
        end

        % Close request function: InstructivodelprocesoUIFigure
        function InstructivodelprocesoUIFigureCloseRequest(app, event)
            app.Procesoapp.InstructivoButton.Enable = "on";
            delete(app)
            
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InstructivodelprocesoUIFigure = uifigure('Visible', 'off');
            app.InstructivodelprocesoUIFigure.Name = 'Instructivo del proceso';
            app.InstructivodelprocesoUIFigure.Icon = 'icono.png';
            app.InstructivodelprocesoUIFigure.CloseRequestFcn = createCallbackFcn(app, @InstructivodelprocesoUIFigureCloseRequest, true);
            app.IdiomaLanguageMenu = uimenu(app.InstructivodelprocesoUIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Separator = 'on';
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Separator = 'on';
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.InstructivodelprocesoUIFigure);
            app.Tree = uitree(app.Panel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InformacinNode = uitreenode(app.Tree);
            app.InformacinNode.NodeData = 1;
            app.InformacinNode.Icon = 'instrucciones1.svg';
            app.InformacinNode.Text = 'Información';
            app.MetodologadelprocesoNode = uitreenode(app.Tree);
            app.MetodologadelprocesoNode.NodeData = 2;
            app.MetodologadelprocesoNode.Text = 'Metodología del proceso';
            app.Paso1Node = uitreenode(app.MetodologadelprocesoNode);
            app.Paso1Node.NodeData = 3;
            app.Paso1Node.Icon = 'distribucion.svg';
            app.Paso1Node.Text = 'Paso 1';
            app.Paso2Node = uitreenode(app.MetodologadelprocesoNode);
            app.Paso2Node.NodeData = 4;
            app.Paso2Node.Icon = 'rosa.svg';
            app.Paso2Node.Text = 'Paso 2';
            app.Paso3Node = uitreenode(app.MetodologadelprocesoNode);
            app.Paso3Node.NodeData = 5;
            app.Paso3Node.Icon = 'recursos.svg';
            app.Paso3Node.Text = 'Paso 3';
            app.Paso4Node = uitreenode(app.MetodologadelprocesoNode);
            app.Paso4Node.NodeData = 6;
            app.Paso4Node.Icon = 'turbina.svg';
            app.Paso4Node.Text = 'Paso 4';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image8 = uiimage(app.Tab);
            app.Image8.ImageSource = 'distribucion.svg';
            app.ElaplicativofuediseadopararealizarelprocesopasoapasoLabel = uilabel(app.Tab);
            app.ElaplicativofuediseadopararealizarelprocesopasoapasoLabel.Text = {'Para determinar el recurso eólico en un lugar es necesario  realizar las siguientes mediciones: '; 'presión atmosférica, temperatura del lugar, altura sobre el nivel del mar y por supuesto velocidad '; 'del viento, entre otros. []  '; ''; 'El aplicativo fue diseñado para realizarlo paso a paso para que haga la identificación del proceso'; 'de diseño. Comenzando con la "Distribución del viento" y terminando con ''''Sugerencia Turbina''''.'; ''; 'Una vez terminado de realizar el proceso, tenga en cuenta no podra realizar un nuevo proceso '; 'hasta que hayacerrado la actual ventana presionando el botón ''''Finalizar proceso''''.'};
            app.Image8_2 = uiimage(app.Tab);
            app.Image8_2.ImageSource = 'rosa.svg';
            app.Image8_3 = uiimage(app.Tab);
            app.Image8_3.ImageSource = 'recursos.svg';
            app.Image8_4 = uiimage(app.Tab);
            app.Image8_4.ImageSource = 'turbina.svg';
            app.Paso1Tab = uitab(app.TabGroup);
            app.Paso1Tab.Title = 'Paso1';
            app.Image9 = uiimage(app.Paso1Tab);
            app.Image9.ImageSource = 'distribucion.svg';
            app.AlobtenerlavelocidadmediadelosdatosindicarsiesviableLabel = uilabel(app.Paso1Tab);
            app.AlobtenerlavelocidadmediadelosdatosindicarsiesviableLabel.Text = {'Al obtener la velocidad media de los datos es un aspecto que puede'; 'indicar la viabilidad de ubicar un aerogenerador.'};
            app.TextArea = uitextarea(app.Paso1Tab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'En el procesamiento de los datos, inicialmente se realiza un histograma para determinar en que velocidades [m/s] se encuentra la mayor parte de los datos. En el eje x se encuentra la velocidades que se presentan en los datos. En el eje y se encuentra la frecuencia que se repite dichas velocidades. '; ''; 'En la elaboración del histograma es necesario tener en cuenta el número de clases, que está dado por la regla de Sturges, la cual se presenta a continuación[]: '; ''; '                                                     No. Clases = 1 +  3.32log (N)                          (1)'; ''; 'donde N es el número de datos de la muestra.'; ''; 'La distribución de Weibull es una distribución de densidad de probabilidad, permite conocer las características del viento. Esta gráfica es de gran importancia ya que las muestra que las velocidades de viento elevadas son poco frecuentes y velocidades de viento regulares son eventos que se repiten con mayor frecuencia. []'; ''; 'Para la construcción de la distribución de Weibull se toma el histograma construido y luego se halla los parámetros k y c (los cuales ya son determinados por el aplicativo), dónde:'; ''; '- k : Factor de forma, que caracteriza la asimetría o sesgo de la función.'; '- c : factor de escala (m/s), valor próximo a la velocidad media.'; ''; 'Aparte el programa también muestra la distribución Normal, para observar el comportamiento de los datos.'};
            app.Paso2Tab = uitab(app.TabGroup);
            app.Paso2Tab.Title = 'Paso2';
            app.Image9_2 = uiimage(app.Paso2Tab);
            app.Image9_2.ImageSource = 'rosa.svg';
            app.LarosadevientosconsisteenundiagramapolarenelcualsedefinenLabel = uilabel(app.Paso2Tab);
            app.LarosadevientosconsisteenundiagramapolarenelcualsedefinenLabel.Text = {'La rosa de vientos consiste en un diagrama polar en el cual'; 'se definen para diferentes direcciones, valores relacionados'; 'con la velocidad y la dirección del viento. Con la rosa de vientos '; 'sepuede ubicar los generadores. En general no coincide la'; 'dirección dominante con la mayor intensidad del viento, ya '; 'que en muchos casos los vientos más intensos no son los '; 'que soplan más horas al año procedentes de una determinada '; 'dirección. []'};
            app.ConlarosadevientossepuedeubicarlosaerogeneradoresLabel = uilabel(app.Paso2Tab);
            app.ConlarosadevientossepuedeubicarlosaerogeneradoresLabel.Text = {'Si una gran parte de la energía del viento viene de una dirección particular, lo más preferible '; 'colocar una turbina eólica y que tenga la menor cantidad de obstáculos posibles en esa '; 'dirección (terreno más liso posible). []'};
            app.Paso3Tab = uitab(app.TabGroup);
            app.Paso3Tab.Title = 'Paso3';
            app.Image10 = uiimage(app.Paso3Tab);
            app.Image10.ImageSource = 'recursos.svg';
            app.LosparmetrosdelasvariablesdisponiblesLabel = uilabel(app.Paso3Tab);
            app.LosparmetrosdelasvariablesdisponiblesLabel.Text = {'Dentro de las variables que pueden ser halladas o disponibles'; 'con los datos suministrados. Se realiza los cálculos para '; 'obtener el potencial energético disponible; parámetros como'; 'la velocidad promedio, intensidad, temperatura promedio, '; 'densidad del aire, son los que el aplicativo esta diseñado '; 'para obtener.'; ''};
            app.LosparmetrosdelasvariablesdisponiblesLabel_2 = uilabel(app.Paso3Tab);
            app.LosparmetrosdelasvariablesdisponiblesLabel_2.Text = {'Tener en cuenta que una equivocada asignación de las variables en la configuración '; 'del archivo puede provocar resultados erróneos o no calculables.'; ''; 'Se recomienda tener en cuenta las variables que el aplicativo necesita para el '; 'desarrollo del proceso en cada paso.'};
            app.Paso4Tab = uitab(app.TabGroup);
            app.Paso4Tab.Title = 'Paso4';
            app.Image11 = uiimage(app.Paso4Tab);
            app.Image11.ImageSource = 'turbina.svg';
            app.ElaplicativoLabel = uilabel(app.Paso4Tab);
            app.ElaplicativoLabel.Text = {'El aplicativo basandose en los datos suministrados '; 'con la velocidad del viento, mostrara una serie de'; 'sugerencias de turbina para aerogeneradores.'; ''; 'Nota: Es posible que la base de datos implementada'; 'no corresponda a la totalidad de alternativas que '; 'puede disponerse en el mercado.'};
            app.Image7 = uiimage(app.Panel);
            app.Image7.ImageSource = 'iconogrande.svg';
            app.Panel_2 = uipanel(app.InstructivodelprocesoUIFigure);
            % Show the figure after all components are created
            app.InstructivodelprocesoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Instructivo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InstructivodelprocesoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InstructivodelprocesoUIFigure)
        end
    end
end