classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenudeInicioUIFigure         matlab.ui.Figure
        IdiomaLanguageMenu           matlab.ui.container.Menu
        EnglishMenu                  matlab.ui.container.Menu
        EspaolMenu                   matlab.ui.container.Menu
        DiseoElicoWindDesign10Label  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        CuadrodeInicioPanel          matlab.ui.container.Panel
        DetallesdelarchivoPanel      matlab.ui.container.Panel
        InstruccionesButton          matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        BienvenidoausuarioantesdesubirelarchivoLabel  matlab.ui.control.Label
        SiButton                     matlab.ui.control.Button
        NoButton                     matlab.ui.control.Button
        Panel_archivo                matlab.ui.container.Panel
        ComenzarprocesoButton        matlab.ui.control.Button
        SeleccionarVariablesButton   matlab.ui.control.Button
        NombredelArchivoSubidoEditFieldLabel  matlab.ui.control.Label
        NombredelArchivoSubidoEditField  matlab.ui.control.EditField
        SubirArchivoButton           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        SoporteButton                matlab.ui.control.Button
        Image5                       matlab.ui.control.Image
    end

%     AUTHORS
%
%     [1] John Jairo Guerrero Moreno
%     [2] Diego Julián Rodríguez Patarroyo
%     [3] Julián Andrés Salamanca Bernal
%
%     Universidad Distrital Francisco José de Caldas
%
%    Facultad de Ingeniería
%    Proyecto Curricular Ingeniería Eléctrica
%    [1,2] Laboratorio de Investigación de Fuentes Alternativas de Energía - LIFAE
%    [3] Grupo de Física e Informática - FISINFOR
%    Emails:
%    [1] jjguerrerom@correo.udistrital.edu.co,
%    [2] djrodriguezp@udistrital.edu.co,
%    [3] jasalamanca@udistrital.edu.co
%    December 1st, 2020


%COPYRIGHT
%
%     This file is part of SCWindDesign.
%     SCWindDesign is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
%
%     SCWindDesign is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
%
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    
    
    properties (Access = private)
        Procesoapp % objeto para llamar el app Proceso
        file; %nombre del archivo que se sube
        path; %ruta del archivo que se sube
        datos; %los datos que contiene el archivo que se ha subido
        Settingapp % objeto para llamar el app Setting
        Instruccionesapp % objeto para llamar el app Instrucciones
        Soporteapp; % objeto para llamar el app Soporte
        ws = 0;
        wd = 0;
        bp = 0;
        at = 0;
        uws;
        uwd;
        ubp;
        uat;
        nombrews = "";
        nombrewd = "";
        nombrebp = "";  
        nombreat = "";
        bar;
        texto;
        idioma;
        lan;
        total = 0;
    end
     
    
    methods (Access = public)
        
        function actualizar(app,ws,wd,bp,at,uws,uwd,ubp,uat,nombrews,nombrewd,nombrebp,nombreat)
            app.ws = ws;
            app.wd = wd;
            app.bp = bp;
            app.at = at;
            app.uws = uws;
            app.uwd = uwd;
            app.ubp = ubp;
            app.uat = uat;
            app.nombrews = nombrews;
            app.nombrewd = nombrewd;
            app.nombrebp = nombrebp;
            app.nombreat = nombreat;
            
        end
        
        function actualizar1(app,bar,texto)
            app.bar.XData = bar;
            app.texto.String = texto;
            
            if app.ComenzarprocesoButton.Enable == "on"
                app.bar.XData = [0 1 1 0];
                app.texto.String = sprintf('%.0f%%',100);
            end
        end
        
        function guardaridiomas(app,idioma)
            app.DiseoElicoWindDesign10Label.Text = buscador(app, idioma, 'titulo');
            app.CuadrodeInicioPanel.Title = buscador(app,idioma, 'cuadroinicio');
            app.DetallesdelarchivoPanel.Title = buscador(app,idioma, 'detallesarchivo');
            app.InstruccionesButton.Text = buscador(app,idioma,'instrucciones');
            app.InstruccionesButton.Tooltip = buscador(app,idioma, 'instruccionestool');
            app.BienvenidoausuarioantesdesubirelarchivoLabel.Text = buscador(app,idioma, 'bienvenida'); 
            app.SiButton.Text = buscador(app,idioma, 'siboton');
            app.ComenzarprocesoButton.Text = buscador(app,idioma, 'comenzarproceso');
            app.SeleccionarVariablesButton.Text = buscador(app,idioma, 'seleccionarvariables');
            app.NombredelArchivoSubidoEditFieldLabel.Text = buscador(app,idioma, 'nombredelarchivo');
            app.SubirArchivoButton.Text = buscador(app,idioma, 'subirarchivo');
            app.UIAxes.Title.String = buscador(app,idioma, 'axestitulo');...toca modificarlo
            app.SoporteButton.Text = buscador(app, idioma, 'soporte');
                if app.SeleccionarVariablesButton.Visible == "on"
                    app.UIAxes.Title.String = buscador(app, idioma, 'axestitulo2');
                end
        
                if app.ComenzarprocesoButton.Visible == "on"
                    app.UIAxes.Title.String = buscador(app, idioma, 'axestitulo3');
                    app.SeleccionarVariablesButton.Text = buscador(app, idioma, 'modificarseleccion');
                end
            
        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('iconos\','imagenes\');
            savepath
            app.Image.ImageSource = "udistrital.png";
            app.Image2.ImageSource = "ie.png";
            app.Image3.ImageSource = "lifae.jpg";
            app.Image4.ImageSource = "fisinfor.jpg";
            app.bar = patch(app.UIAxes,[0 0 0 0],[0 0 1 1],[0.31 0.93 0.58]);
            app.texto = text(app.UIAxes,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            app.UIAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.UIAxes);
            app.idioma = "English";
            guardaridiomas(app,app.idioma);
            ...app.bar.XData = [0 0 0 0];       
            ...app.texto.String = sprintf('%.0f%%',round(0.01*100)); 
            ...drawnow


        end

        % Button pushed function: SubirArchivoButton
        function SubirArchivoButtonPushed(app, event)
            try
                [app.file,app.path]= uigetfile({'*.txt; *.xlsx; *.xls;', '*.txt,*.xlsx,*.xls'}, buscador(app, app.idioma, 'ugetfile'));
                app.MenudeInicioUIFigure.Visible = "off";
                app.MenudeInicioUIFigure.Visible = "on";
                d = uiprogressdlg(app.MenudeInicioUIFigure,'Title', buscador(app,app.idioma, 'cargando'),"Indeterminate","on");
                app.datos = readtable(fullfile(app.path,app.file)); 
                close(d);
                app.ws = 0;
                app.wd = 0;
                app.bp = 0;
                app.at = 0;
                app.uws = buscador(app, app.idioma, 'u');
                app.uwd = buscador(app, app.idioma, 'u');
                app.ubp = buscador(app, app.idioma, 'u');
                app.uat = buscador(app, app.idioma, 'u');
                app.nombrews = "";
                app.nombrewd = "";
                app.nombrebp = "";
                app.nombreat = "";
                uialert(app.MenudeInicioUIFigure,  fullfile( buscador(app, app.idioma, 'cargado') , app.file), buscador(app, app.idioma, 'confirmacion'),'Icon','success');
                app.ComenzarprocesoButton.Visible = "off";
                app.SeleccionarVariablesButton.Text = buscador(app, app.idioma, 'seleccionarvariables');
                app.SeleccionarVariablesButton.Visible = "on";
                app.NombredelArchivoSubidoEditFieldLabel.Visible = "on";
                app.NombredelArchivoSubidoEditField.Visible = "on";
                app.NombredelArchivoSubidoEditField.Value = fullfile(app.file);
                app.bar.XData = [0 0.5 0.5 0];
                app.UIAxes.Title.String = buscador(app,app.idioma, 'axestitulo2');
                app.texto.String = sprintf('%.0f%%',50); 
            catch
                uialert(app.MenudeInicioUIFigure, buscador(app, app.idioma, 'cancelado') , buscador(app,app.idioma, 'informacion')  ,'Icon',"info");
                app.NombredelArchivoSubidoEditFieldLabel.Visible = "off";
                app.NombredelArchivoSubidoEditField.Visible = "off";
                app.SeleccionarVariablesButton.Visible = "off";
                app.ComenzarprocesoButton.Visible = "off";
                disp('probando');
                app.UIAxes.Title.String = buscador(app, app.idioma, 'axestitulo');
                app.bar.XData = [0 0 0 0];
                app.texto.String = sprintf('%.0f%%',0);
            end
        end

        % Button pushed function: ComenzarprocesoButton
        function ComenzarprocesoButtonPushed(app, event)
            app.ComenzarprocesoButton.Enable = "off";
            f = uiprogressdlg(app.MenudeInicioUIFigure,"Title", buscador(app, app.idioma, 'cargando') ,"Indeterminate","on");
            app.SeleccionarVariablesButton.Enable = "off";
            app.SubirArchivoButton.Enable = "off";
            app.Procesoapp = Proceso(app,app.file, app.datos,app.ws,app.wd,app.bp,app.at,app.nombrews,app.nombrewd,app.nombrebp,app.nombreat,app.idioma);
            close(f);
        end

        % Close request function: MenudeInicioUIFigure
        function MenudeInicioUIFigureCloseRequest(app, event)
            
            
            if app.ComenzarprocesoButton.Enable == "off" || app.SeleccionarVariablesButton.Enable == "off" || app.InstruccionesButton.Enable == "off" || app.SoporteButton.Enable == "off"
                uialert(app.MenudeInicioUIFigure, buscador(app, app.idioma, 'cerrar'), buscador(app, app.idioma, 'informacion'), 'Icon', "info");
                
            end
            if app.ComenzarprocesoButton.Enable == "on" && app.SeleccionarVariablesButton.Enable == "on" && app.InstruccionesButton.Enable == "on" && app.SoporteButton.Enable == "on"
                delete(app);
            end
            
        end

        % Button pushed function: SeleccionarVariablesButton
        function SeleccionarVariablesButtonPushed(app, event)
            app.SeleccionarVariablesButton.Enable = "off";
            app.Settingapp = Settings(app,app.datos,app.ws,app.wd,app.bp,app.at,app.uws,app.uwd,app.ubp,app.uat,app.nombrews,app.nombrewd,app.nombrebp,app.nombreat,app.idioma);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.Panel_archivo.Visible = "on";
            app.DetallesdelarchivoPanel.Visible = "on";
            app.CuadrodeInicioPanel.Visible = "off";
            app.Panel.Visible = "off";
            app.InstruccionesButton.Enable = "on";
            app.InstruccionesButton.Visible = "on";
        end

        % Button pushed function: SiButton
        function SiButtonPushed(app, event)
            app.InstruccionesButton.Enable = "off";
            f = uiprogressdlg(app.MenudeInicioUIFigure, "Title", buscador(app, app.idioma, 'cargando') ,"Indeterminate","on");
            app.Instruccionesapp = Instrucciones(app,app.idioma);
            close(f);
            app.CuadrodeInicioPanel.Visible = "off";
            app.Panel.Visible = "off";
            app.Panel_archivo.Visible = "on";
            app.DetallesdelarchivoPanel.Visible = "on";
            app.InstruccionesButton.Visible = "on";
            
        end

        % Button pushed function: InstruccionesButton
        function InstruccionesButtonPushed(app, event)
            f = uiprogressdlg(app.MenudeInicioUIFigure, "Title", buscador(app, app.idioma, 'cargando') ,"Indeterminate","on");
            app.InstruccionesButton.Enable = "off";
            app.Instruccionesapp = Instrucciones(app,app.idioma);
            close(f);
            
        end

        % Button pushed function: SoporteButton
        function SoporteButtonPushed(app, event)
            app.SoporteButton.Enable = "off";
            app.Soporteapp = Soporte(app, app.idioma);
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenudeInicioUIFigure = uifigure('Visible', 'off');
            app.MenudeInicioUIFigure.Name = 'Menu de Inicio';
            app.MenudeInicioUIFigure.Icon = 'icono.png';
            app.MenudeInicioUIFigure.CloseRequestFcn = createCallbackFcn(app, @MenudeInicioUIFigureCloseRequest, true);
            app.IdiomaLanguageMenu = uimenu(app.MenudeInicioUIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Separator = 'on';
            app.EnglishMenu.Checked = 'on';
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Separator = 'on';
            app.EspaolMenu.Text = 'Español';
            app.DiseoElicoWindDesign10Label = uilabel(app.MenudeInicioUIFigure);
            app.DiseoElicoWindDesign10Label.Text = 'Diseño Eólico (Wind Design 1.0)';
            app.Image = uiimage(app.MenudeInicioUIFigure);
            app.Image.ImageSource = 'udistrital.png';
            app.Image2 = uiimage(app.MenudeInicioUIFigure);
            app.Image2.ImageSource = 'ie.png';
            app.Image3 = uiimage(app.MenudeInicioUIFigure);
            app.Image3.ImageSource = 'lifae.jpg';
            app.Image4 = uiimage(app.MenudeInicioUIFigure);
            app.Image4.ImageSource = 'fisinfor.jpg';
            app.CuadrodeInicioPanel = uipanel(app.MenudeInicioUIFigure);
            app.CuadrodeInicioPanel.Title = 'Cuadro de Inicio';
            app.DetallesdelarchivoPanel = uipanel(app.MenudeInicioUIFigure);
            app.DetallesdelarchivoPanel.Title = 'Detalles del archivo';
            app.DetallesdelarchivoPanel.Visible = 'off';
            app.InstruccionesButton = uibutton(app.MenudeInicioUIFigure, 'push');
            app.InstruccionesButton.ButtonPushedFcn = createCallbackFcn(app, @InstruccionesButtonPushed, true);
            app.InstruccionesButton.Icon = 'instrucciones.svg';
            app.InstruccionesButton.Visible = 'off';
            app.InstruccionesButton.Tooltip = {'Information for uploading data files and their corresponding formats.'};
            app.InstruccionesButton.Text = 'Instrucciones';
            app.Panel = uipanel(app.MenudeInicioUIFigure);
            app.BienvenidoausuarioantesdesubirelarchivoLabel = uilabel(app.Panel);
            app.BienvenidoausuarioantesdesubirelarchivoLabel.Text = 'Bienvenido(a) usuario(a),  ¿Es la primera vez usando este aplicativo?';
            app.SiButton = uibutton(app.Panel, 'push');
            app.SiButton.ButtonPushedFcn = createCallbackFcn(app, @SiButtonPushed, true);
            app.SiButton.Text = 'Si';
            app.NoButton = uibutton(app.Panel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.Panel_archivo = uipanel(app.MenudeInicioUIFigure);
            app.Panel_archivo.Visible = 'off';
            app.ComenzarprocesoButton = uibutton(app.Panel_archivo, 'push');
            app.ComenzarprocesoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarprocesoButtonPushed, true);
            app.ComenzarprocesoButton.Icon = 'start.svg';
            app.ComenzarprocesoButton.Visible = 'off';
            app.ComenzarprocesoButton.Text = {'Comenzar'; 'proceso'};
            app.SeleccionarVariablesButton = uibutton(app.Panel_archivo, 'push');
            app.SeleccionarVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarVariablesButtonPushed, true);
            app.SeleccionarVariablesButton.Icon = 'conf.svg';
            app.SeleccionarVariablesButton.Visible = 'off';
            app.SeleccionarVariablesButton.Text = 'Seleccionar Variables';
            app.NombredelArchivoSubidoEditFieldLabel = uilabel(app.Panel_archivo);
            app.NombredelArchivoSubidoEditFieldLabel.Visible = 'off';
            app.NombredelArchivoSubidoEditFieldLabel.Text = 'Nombre del Archivo Subido:';
            app.NombredelArchivoSubidoEditField = uieditfield(app.Panel_archivo, 'text');
            app.NombredelArchivoSubidoEditField.Editable = 'off';
            app.NombredelArchivoSubidoEditField.Visible = 'off';
            app.SubirArchivoButton = uibutton(app.Panel_archivo, 'push');
            app.SubirArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SubirArchivoButtonPushed, true);
            app.SubirArchivoButton.Icon = 'subir.svg';
            app.SubirArchivoButton.Text = 'Subir Archivo';
            app.UIAxes = uiaxes(app.Panel_archivo);
            title(app.UIAxes, 'Paso 1: Sube el archivo con la extensión correspondiente')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.SoporteButton = uibutton(app.MenudeInicioUIFigure, 'push');
            app.SoporteButton.ButtonPushedFcn = createCallbackFcn(app, @SoporteButtonPushed, true);
            app.SoporteButton.Text = 'Soporte';
            app.Image5 = uiimage(app.MenudeInicioUIFigure);
            app.Image5.ImageSource = 'iconogrande.svg';
            % Show the figure after all components are created
            app.MenudeInicioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenudeInicioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenudeInicioUIFigure)
        end
    end
end