classdef Proceso < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcesodeAnlisisUIFigure        matlab.ui.Figure
        IdiomaLanguageMenu              matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        EspaolMenu                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        InstructivoButton               matlab.ui.control.Button
        FinalizarprocesoButton          matlab.ui.control.Button
        AplicacionesPanel               matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        DistribucindelVientoButton      matlab.ui.control.Button
        RosadeVientosButton             matlab.ui.control.Button
        RecursosDisponiblesButton       matlab.ui.control.Button
        SugerenciaTurbinaButton         matlab.ui.control.Button
        DatosbsicosPanel                matlab.ui.container.Panel
        Panel_8                         matlab.ui.container.Panel
        NombredelArchivoEditFieldLabel  matlab.ui.control.Label
        NombredelArchivoEditField       matlab.ui.control.EditField
        WindDirectionColumnaEditFieldLabel  matlab.ui.control.Label
        WindDirectionColumnaEditField   matlab.ui.control.EditField
        AverageTemperatureColumnaEditFieldLabel  matlab.ui.control.Label
        AverageTemperatureColumnaEditField  matlab.ui.control.EditField
        WindSpeedColumnaEditFieldLabel  matlab.ui.control.Label
        WindSpeedColumnaEditField       matlab.ui.control.EditField
        BarometricPressureColumnaEditFieldLabel  matlab.ui.control.Label
        BarometricPressureColumnaEditField  matlab.ui.control.EditField
        Panel_4                         matlab.ui.container.Panel
        DistribucindelVientoTab         matlab.ui.container.Tab
        InstruccionesPanel              matlab.ui.container.Panel
        ParmetrosNormalPanel            matlab.ui.container.Panel
        ParmetrosWeibullPanel           matlab.ui.container.Panel
        Panel_7                         matlab.ui.container.Panel
        RecomendadoLabel                matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        mesDropDownLabel                matlab.ui.control.Label
        mesDropDown                     matlab.ui.control.DropDown
        NmerodebinsDropDownLabel        matlab.ui.control.Label
        NmerodebinsDropDown             matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        Panel_10                        matlab.ui.container.Panel
        EscalamsEditFieldLabel          matlab.ui.control.Label
        EscalamsEditField               matlab.ui.control.NumericEditField
        kformaEditFieldLabel            matlab.ui.control.Label
        kformaEditField                 matlab.ui.control.NumericEditField
        Panel_9                         matlab.ui.container.Panel
        mumsEditFieldLabel              matlab.ui.control.Label
        mumsEditField                   matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        RosadevientosTab                matlab.ui.container.Tab
        GraficarButton_2                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        DistribucindelvientoPanel       matlab.ui.container.Panel
        Panel_11                        matlab.ui.container.Panel
        RecursosdisponiblesTab          matlab.ui.container.Tab
        VariablesPanel                  matlab.ui.container.Panel
        Panel_12                        matlab.ui.container.Panel
        PresinpromedioLabel             matlab.ui.control.Label
        AlturasobreelniveldelmarLabel   matlab.ui.control.Label
        TemperaturapromedioLabel        matlab.ui.control.Label
        DensidaddelaireLabel            matlab.ui.control.Label
        IntensidadLabel                 matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        DropDown_7                      matlab.ui.control.DropDown
        VelocidadpromedioLabel          matlab.ui.control.Label
        Button_6                        matlab.ui.control.Button
        DropDownLabel_7                 matlab.ui.control.Label
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3Label                 matlab.ui.control.Label
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_4Label                 matlab.ui.control.Label
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_5Label                 matlab.ui.control.Label
        DropDown_5                      matlab.ui.control.DropDown
        CalcularButton                  matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        DescripcinPanel                 matlab.ui.container.Panel
        Panel_13                        matlab.ui.container.Panel
        TextArea_7                      matlab.ui.control.TextArea
        SugerenciaTurbinaparaAerogeneradorTab  matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ComprobarButton                 matlab.ui.control.Button
    end

%     AUTHORS
%
%     [1] John Jairo Guerrero Moreno
%     [2] Diego Julián Rodríguez Patarroyo
%     [3] Julián Andrés Salamanca Bernal
%
%     Universidad Distrital Francisco José de Caldas
%
%    Facultad de Ingeniería
%    Proyecto Curricular Ingeniería Eléctrica
%    [1,2] Laboratorio de Investigación de Fuentes Alternativas de Energía - LIFAE
%    [3] Grupo de Física e Informática - FISINFOR
%    Emails:
%    [1] jjguerrerom@correo.udistrital.edu.co,
%    [2] djrodriguezp@udistrital.edu.co,
%    [3] jasalamanca@udistrital.edu.co
%    December 1st, 2020


%COPYRIGHT
%
%     This file is part of SCWindDesign.
%     SCWindDesign is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
%
%     SCWindDesign is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
%
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.

    properties (Access = private)
        CallingApp; % Variable para llamar de regreso a la app IdiomaLanguageMenu
        datos; % variable de los datos
        ws;
        wd;
        bp;
        at;
        nombrews;
        nombrewd;
        nombrebp;
        nombreat;
        bines;
        datows;
        datowd;
        datobp;
        datoat;
        h;
        distnormal;
        distweibull;
        Ro;
        polar;
        E;
        Instructivoapp;
        idioma;
        opcion = "0";
        cerrar1;
        ax;
        
    end


    methods (Access = public)
        
        function guardaridiomas(app, idioma) 
            
            app.DistribucindelVientoButton.Text = buscador(app, idioma, 'distribucion');
            app.RosadeVientosButton.Text = buscador(app, idioma, 'rosa');
            app.RecursosDisponiblesButton.Text = buscador(app, idioma, 'recursos');
            app.SugerenciaTurbinaButton.Text = buscador(app, idioma, 'turbina');
            app.DatosbsicosPanel.Title = buscador(app, idioma, 'datos');
            app.InstructivoButton.Text = buscador(app, idioma, 'instructivo');
            app.FinalizarprocesoButton.Text = buscador(app, idioma, 'finalizar');
            app.AplicacionesPanel.Title = buscador(app, idioma, 'aplicaciones');
            app.NombredelArchivoEditFieldLabel.Text = buscador(app, idioma, 'nombredelarchivo');
            app.WindSpeedColumnaEditFieldLabel.Text = buscador(app, idioma, 'windspeed') + newline + buscador(app, idioma, 'columna');
            app.BarometricPressureColumnaEditFieldLabel.Text = buscador(app, idioma, 'barometric') + newline + buscador(app, idioma, 'columna');
            app.WindDirectionColumnaEditFieldLabel.Text = buscador(app, idioma, 'winddirection') + newline + buscador(app, idioma, 'columna');
            app.AverageTemperatureColumnaEditFieldLabel.Text = buscador(app, idioma, 'temperatura') + newline + buscador(app , idioma , 'columna');
            app.MenuTab.Title = buscador(app, idioma, 'menu');
            
            app.DistribucindelVientoTab.Title = buscador(app, idioma, 'stepone');
            app.UIAxes.Title.String = buscador(app, idioma, 'graforiginal');
            app.UIAxes2.Title.String = buscador(app, idioma, 'distnormal');
            app.UIAxes3.Title.String = buscador(app, idioma, 'distweibull');
            app.UIAxes.XLabel.String = buscador(app, idioma, 'velocidaddelviento'); app.UIAxes.YLabel.String = buscador(app, idioma, 'frecuencia');
            app.UIAxes2.XLabel.String = buscador(app, idioma, 'velocidaddelviento'); app.UIAxes2.YLabel.String = buscador(app, idioma, 'frecuencia'); 
            app.UIAxes3.XLabel.String = buscador(app, idioma, 'velocidaddelviento'); app.UIAxes3.YLabel.String = buscador(app, idioma, 'frecuencia'); 
            app.InstruccionesPanel.Title = buscador(app, idioma, 'instrucciones');
            app.TextArea_5.Value = buscador(app, idioma, 'inspasouno');
            app.ParmetrosNormalPanel.Title = buscador(app, idioma, 'parnormal');
            app.ParmetrosWeibullPanel.Title = buscador(app, idioma, 'parweibull');
            app.EscalamsEditFieldLabel.Text = buscador(app, idioma, 'escala');
            app.kformaEditFieldLabel.Text = buscador(app, idioma, 'kform');
            app.mesDropDownLabel.Text = buscador(app, idioma, 'month');
            app.NmerodebinsDropDownLabel.Text = buscador(app, idioma, 'nbins');
            app.GraficarButton.Text = buscador(app, idioma, 'graficar');
            app.NmerodebinsDropDownLabel.Tooltip = buscador(app, idioma, 'bins');
            app.RecomendadoLabel.Text = buscador(app, idioma, 'recomendado');
            app.RecomendadoLabel.Tooltip = buscador(app, idioma, 'binsoptimo');
            app.sigmaEditFieldLabel.Tooltip = buscador(app, idioma, 'sigma');
            app.kformaEditFieldLabel.Tooltip = buscador(app, idioma, 'k');
            app.EscalamsEditFieldLabel.Tooltip = buscador(app, idioma, 'c');
            app.Label_2.Tooltip = buscador(app, idioma, 'bines');
            
            app.RosadevientosTab.Title = buscador(app, idioma, 'steptwo');
            app.DistribucindelvientoPanel.Title = buscador(app, idioma, 'winddirection');
            app.GraficarButton_2.Text = buscador(app, idioma, 'graph');
            
            app.RecursosdisponiblesTab.Title = buscador(app, idioma, 'stepthree');
            app.DescripcinPanel.Title = buscador(app, idioma, 'descripcion');
            app.VelocidadpromedioLabel.Text = buscador(app, idioma, 'velocidad');
            app.PresinpromedioLabel.Text = buscador(app, idioma, 'presion');
            app.AlturasobreelniveldelmarLabel.Text = buscador(app, idioma, 'altura');
            app.TemperaturapromedioLabel.Text = buscador(app, idioma, 'temperatura');
            app.DensidaddelaireLabel.Text = buscador(app, idioma, 'densidad');
            app.IntensidadLabel.Text = buscador(app, idioma, 'intensidad');
            app.CalcularButton.Text = buscador(app, idioma, 'calcular');
            
            app.SugerenciaTurbinaparaAerogeneradorTab.Title = buscador(app, idioma, 'stepfour');
            app.ComprobarButton.Text = buscador(app, idioma, 'comprobar');
            
        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, menuapp, nombre, datos, ws, wd, bp, at, nombrews, nombrewd, nombrebp, nombreat, idioma)
            app.idioma = idioma;
            if idioma == "Español"
                app.EspaolMenu.Checked = "on";
                app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end
            progreso = uiprogressdlg(app.ProcesodeAnlisisUIFigure,"Title", buscador(app, app.idioma, 'cargando'),"Indeterminate","on");
            app.CallingApp = menuapp;
            app.DistribucindelVientoTab.Parent = [];
            app.RosadevientosTab.Parent = [];
            app.RecursosdisponiblesTab.Parent = [];
            app.SugerenciaTurbinaparaAerogeneradorTab.Parent = [];
            ... app.MenuTab.Parent = [];
            app.NombredelArchivoEditField.Value = nombre;
            app.datos = datos;
            app.ws = ws;
            app.wd = wd;
            app.bp = bp;
            app.at = at;
            app.WindSpeedColumnaEditField.Value = nombrews;
            app.WindDirectionColumnaEditField.Value = nombrewd;
            app.BarometricPressureColumnaEditField.Value = nombrebp;
            app.AverageTemperatureColumnaEditField.Value = nombreat;
            app.TabGroup.Visible = "on";
            ...app.Tab.Parent = [];
            close(progreso);


        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
             try
             cla(app.UIAxes3,'reset');
             cla(app.UIAxes2,'reset')
             app.UIAxes.XLabel.String = buscador(app, app.idioma, 'velocidaddelviento'); app.UIAxes.YLabel.String = buscador(app, app.idioma, 'frecuencia'); 
             app.UIAxes2.XGrid = "on"; app.UIAxes2.YGrid = "on";
             app.UIAxes2.XMinorGrid = "on"; app.UIAxes2.YMinorGrid = "on";
             app.UIAxes2.XColor = "white"; app.UIAxes2.YColor = "white";
             app.UIAxes2.Title.String = buscador(app, app.idioma, 'distnormal'); app.UIAxes2.XLabel.String = buscador(app, app.idioma, 'velocidaddelviento'); app.UIAxes2.YLabel.String = buscador(app, app.idioma, 'frecuencia');
             app.UIAxes3.XGrid = "on"; app.UIAxes3.YGrid = "on";
             app.UIAxes3.XMinorGrid = "on"; app.UIAxes3.YMinorGrid = "on";
             app.UIAxes3.XColor = "white"; app.UIAxes3.YColor = "white";
             app.UIAxes3.Title.String = buscador(app, app.idioma, 'distweibull'); app.UIAxes3.XLabel.String = buscador(app, app.idioma, 'velocidaddelviento'); app.UIAxes3.YLabel.String = buscador(app, app.idioma, 'frecuencia' );
             d = uiprogressdlg(app.ProcesodeAnlisisUIFigure,"Title", buscador(app, app.idioma, 'obtgraph'),"Indeterminate","on");
             app.UIAxes.XColor = "black"; app.UIAxes.YColor= "black";
             app.UIAxes2.XColor = "black"; app.UIAxes2.YColor = "black";
             app.UIAxes3.XColor = "black"; app.UIAxes3.YColor = "black";
             app.datows
             app.h = histogram(app.UIAxes,app.datows, str2double(app.bines));
             datoooo = app.datows(app.datows~=0);
             wbl = wblfit(datoooo(datoooo~=0));
             pr = fitdist(app.datows,"Weibull");
             bino = fitdist(app.datows,"Normal");
             [mu, sigma]= normfit(datoooo(datoooo~=0));
             app.distweibull = figure("Visible","off");
             h= histfit(app.datows,str2double(app.bines),"weibull");
             fonly = findobj(app.distweibull,'Type','Axes');
             copyobj(fonly.Children,app.UIAxes3);
             app.UIAxes3.BackgroundColor = "white";
             ...delete(f);
             app.distnormal = figure("Visible","off");
             normal = histfit(app.datows, str2double(app.bines),"normal");
             fnormal = findobj(app.distnormal,'Type', 'Axes');
             copyobj(fnormal.Children, app.UIAxes2);
             app.UIAxes2.BackgroundColor = "white";
             ...delete(n);
             app.EscalamsEditField.Value = (wbl(1));
             app.kformaEditField.Value = (wbl(2));
             app.mumsEditField.Value = mu;
             app.sigmaEditField.Value = sigma;


             close(d);
             
             catch
             
             end


        end

        % Close request function: ProcesodeAnlisisUIFigure
        function ProcesodeAnlisisUIFigureCloseRequest(app, event)
            uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'warning'), buscador(app, app.idioma, 'advertenciaw'));
            
        end

        % Button pushed function: FinalizarprocesoButton
        function FinalizarprocesoButtonPushed(app, event)
           
            if app.InstructivoButton.Enable == "off"
                uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'instructivoclose'), buscador(app, app.idioma, 'informacion'), "Icon","info");
            end
            
            
            if app.InstructivoButton.Enable == "on"
                app.CallingApp.ComenzarprocesoButton.Enable = "on";
                app.CallingApp.SubirArchivoButton.Enable = "on";
                app.CallingApp.SeleccionarVariablesButton.Enable = "on";
                delete(app);
            end


        end

        % Value changed function: mesDropDown
        function mesDropDownValueChanged(app, event)
            value = app.mesDropDown.Value;
            switch value
                case '(...)'
                    uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'selecction'), buscador(app, app.idioma, 'advertenciaw'));
                case 'Todos los datos'
                    app.datows = app.datos(:,app.ws);
                    wsc = table2array(app.datos(:,app.ws));
                    wsc(wsc(:,1)==0,:) = [];
                    app.datows = wsc(:,1)
                    todo = wsc(:,1)
                    
                    todos = todo/mean(todo);
                    optimo = 1 + 3.32*log10(numel(wsc));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                    
                case 'Enero'
                    datoo = table2cell(app.datos(:,1))
                    x = find(startsWith(datoo,"ene"))
                    mes = table2array(app.datos(x,app.ws));
                    mes((mes(:,1)==0),:) = [];
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                    
                case 'Febrero'
                    febrero = table2cell(app.datos(:,1))
                    x = find(startsWith(febrero,"feb"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                   app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Marzo'
                    mar = table2cell(app.datos(:,1))
                    x = find(startsWith(mar,"mar"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                   app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Abril'
                    abr = table2cell(app.datos(:,1))
                    x = find(startsWith(abr,"abr"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Mayo'
                    may = table2cell(app.datos(:,1))
                    x = find(startsWith(may,"may"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Junio'
                    jun = table2cell(app.datos(:,1))
                    x = find(startsWith(jun,"jun"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Julio'
                    jul = table2cell(app.datos(:,1))
                    x = find(startsWith(jul,"jul"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Agosto'
                    ago = table2cell(app.datos(:,1))
                    x = find(startsWith(ago,"ago"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Septiembre'
                    sep = table2cell(app.datos(:,1))
                    x = find(startsWith(sep,"sep"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Octubre'
                    oct = table2cell(app.datos(:,1))
                    x = find(startsWith(oct,"oct"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Noviembre'
                    nov = table2cell(app.datos(:,1))
                    x = find(startsWith(nov,"nov"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
                case 'Diciembre'
                    dic = table2cell(app.datos(:,1))
                    x = find(startsWith(dic,"dic"));
                    mes = table2array(app.datos(x,app.ws));
                    app.datows = mes(:,1);
                    optimo = 1 + 3.32*log10(numel(mes));
                    if isinf(optimo)
                        uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'novalido'),'Error');
                    end
                    app.NmerodebinsDropDown.Enable = "on";
                    app.NmerodebinsDropDownLabel.Enable = "on";
                    app.RecomendadoLabel.Enable = "on";
                    app.Label_2.Enable = "on";
                    app.Label_2.Text = "("+round(optimo)+")";
                    if app.GraficarButton.Enable == "on"
                        GraficarButtonPushed(app,event);
                    end
            end
        end

        % Value changed function: NmerodebinsDropDown
        function NmerodebinsDropDownValueChanged(app, event)
            value = app.NmerodebinsDropDown.Value;
            if app.GraficarButton.Enable == "on"
               app.bines = value; 
               GraficarButtonPushed(app,event);
            end
            app.GraficarButton.Enable = "on";
            app.bines = value; 
            
        end

        % Button pushed function: GraficarButton_2
        function GraficarButton_2Pushed(app, event)
            app.GraficarButton_2.Enable = "off";
            progreso = uiprogressdlg(app.ProcesodeAnlisisUIFigure,"Title", buscador(app, app.idioma, 'cargando'),'Indeterminate','on');
            app.DistribucindelvientoPanel.Visible = "off";
            viento = table2array(app.datos(:,app.wd));
            theta = ((pi*viento)/180);
            app.ax = polaraxes(app.Panel_11);
            
            app.polar = polarhistogram(app.ax,theta);
            
            app.ax.ThetaDir = 'clockwise';
            app.ax.ThetaZeroLocation = "top";
            
            ...polar.DisplayStyle = 'bar';
            axtoolbar(app.ax,{'export'});
            close(progreso);
            app.DistribucindelvientoPanel.Visible = "on";
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            try
                app.CalcularButton.Enable = "off";
                app.DropDown.Enable = "on";
                app.DropDown_2.Enable = "on";
                app.DropDown_3.Enable = "on";
                app.DropDown_4.Enable = "on";
                app.DropDown_5.Enable = "on";
                app.DropDown_7.Enable = "on";
                
                app.datobp = table2array(app.datos(:,app.bp));
                app.datobp = mean(app.datobp);
                app.DropDownLabel.Text = string(app.datobp);
                app.datobp = app.datobp * 1.33322; %convertirlo a mBar
                
            
                h = (1013.25-(mean(app.datobp)))*10;
                app.h = h;
                app.DropDown_2Label.Text = string(h);
            
                app.datoat = table2array(app.datos(:,app.at));
                app.datoat = mean(app.datoat);
                app.DropDown_3Label.Text = string(app.datoat);
                
                app.Ro = 1.225*(288/(mean(app.datoat)+273))*(exp(-((h))/8435));
                app.DropDown_4Label.Text = string(app.Ro);
                
                app.datows = table2array(app.datos(:,app.ws));
                app.DropDownLabel_7.Text = string(mean(app.datows(app.datows~=0)));
                
                valor = app.datows.^3;
                app.E = ((app.Ro)*(mean(valor(valor~=0))))/(2);
                app.DropDown_5Label.Text = string(app.E)


            catch
                uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'sorrynot'), 'Error');
            end 
        end

        % Button pushed function: ComprobarButton
        function ComprobarButtonPushed(app, event)
            bdatos =uiprogressdlg(app.ProcesodeAnlisisUIFigure,"Title", buscador(app, app.idioma, 'cargando'),'Indeterminate',"on");
            app.datows = table2array(app.datos(:,app.ws));
            vmin = min(app.datows(app.datows~=0));
            vmax = max(max(app.datows));
            p = mean(app.datows);
            data = importdata('Aerogeneradores.xlsx');
            Vpmax = data.data(:,5);
            Varranque = data.data(:,3);
            
            u=p>=Vpmax;
            disp(u);
            c=Vpmax(u);
            u2=Varranque<=p;
            disp(u2);
            c2=Varranque(u2);
            f=(u)&(u2);
            disp(f);
            disp(any(u~=0));
            disp(any(u2~=0));
            close(bdatos);
            
            if   any(u~=0)&& any(u2~=0)
                K=data.data(f,:);
                J=data.textdata(f,:);
                datico= (data.textdata(f,[1,10,11,12]));
                cname= buscador(app, app.idioma, 'cname');
                app.UITable.Data = datico;
                app.UITable.ColumnName = cname;
                dato= (data.data(f,:));    
                ...cname2={'Numero de palas','Potencia (Kw)','Velocidad de arranque (m/s)','Velocidad de parada (m/s)','Velocidad Pmx (m/s)', 'Tension (V)','Diametro Rotor  (m)','Altura del Rotor (m)'};
                ...set(handles.uitable2,'data',dato);
                ...set(handles.uitable2,'ColumnName',cname2); 
                ...set(handles.uitable1, 'Visible', 'on');
                ...set(handles.uitable2, 'Visible', 'on');
            else
                uialert(app.ProcesodeAnlisisUIFigure, buscador(app, app.idioma, 'option'), buscador(app, app.idioma, 'informacion'),'Icon',"info");
            end
                        
            
        end

        % Button pushed function: Button_4
        function Button_5Pushed(app, event)
            app.TextArea_7.Value = buscador(app, app.idioma, 'densidadcont');
            app.opcion = "5";
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.datobp = table2array(app.datos(:,app.bp));
            app.datobp = mean(app.datobp);
            ...solo para el caso en que siempre la unidad inicial es mmHg en la configuracion
            switch value
                
                case 'mmHg'
                    app.DropDownLabel.Text = string(app.datobp);
                case 'mbar'
                    datomb = app.datobp * 1.33322;
                    app.datobp = datomb;
                    app.DropDownLabel.Text = string(datomb);
                case 'atm'
                    datoatm = app.datobp/760;
                    app.datobp = datoatm;
                    app.DropDownLabel.Text = string(datoatm);
                case 'psi'
                    datopsi = app.datobp/51.715;
                    app.datobp = datopsi;
                    app.DropDownLabel.Text = string(datopsi);
                case 'hPa'
                    datohp = app.datobp*133.322;
                    app.datobp = datohp;
                    app.DropDownLabel.Text = string(datohp);
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            app.datoat = table2array(app.datos(:,app.at));
            app.datoat = mean(app.datoat);
             
            switch value
                
                case '° C'
                    app.DropDown_3Label.Text = string(app.datoat);
                case '° F'
                    f = ((app.datoat*9)/5)+32;
                    app.datoat = f;
                    app.DropDown_3Label.Text = string(f);
                case 'K'
                    k = app.datoat + 273.15;
                    app.datoat = k;
                    app.DropDown_3Label.Text = string(k);
            end
             
            
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            app.datobp = mean(app.datobp);
            Ro = 1.225*(288/(mean(app.datoat)+273))*(exp(-((app.h))/8435));
            value = app.DropDown_4.Value;
            switch value
                case 'Kg/m^3'
                    app.DropDown_4Label.Text = string(app.Ro);
                case 'lb/gal'
                    lbgal = app.Ro*0.0083454044740898;
                    app.Ro = lbgal;
                    app.DropDown_4Label.Text = string(lbgal);
                case 'lb/ft^3'
                    lbft = app.Ro*0.06242796;
                    app.Ro = lbft;
                    app.DropDown_4Label.Text = string(lbft);
                    
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                   
                    meses = table2cell(app.datos(:,1))
                   
                    x1 = find(startsWith(meses,"ene"));
                    x2 = find(startsWith(meses,"feb"));
                    x3 = find(startsWith(meses,"mar"));
                    x4 = find(startsWith(meses,"abr"));
                    x5 = find(startsWith(meses,"may"));
                    x6 = find(startsWith(meses,"jun"));
                    x7 = find(startsWith(meses,"jul"));
                    x8 = find(startsWith(meses,"ago"));
                    x9 = find(startsWith(meses,"sep"));
                    x10 = find(startsWith(meses,"oct"));
                    x11 = find(startsWith(meses,"nov"));
                    x12 = find(startsWith(meses,"dic"));
                    list = ["(...)"; "Todos los datos"]; ...donde ms es el mes siguiente
                    app.mesDropDown.Items = list;
                    if(isempty(x1)==false) 
                        list = [list; "Enero"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x2)==false)
                        list = [list; "Febrero"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x3)==false)
                        list = [list; "Marzo"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x4)==false)
                        list = [list; "Abril"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x5)==false)
                        list = [list; "Mayo"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x6)==false)
                        list = [list; "Junio"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x7)==false)
                        list = [list; "Julio"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x8)==false)
                        list = [list; "Agosto"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x9)==false)
                        list = [list; "Septiembre"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x10)==false)
                        list = [list; "Octubre"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x11)==false)
                        list = [list; "Noviembre"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x12)==false)
                        list = [list; "Diciembre"];
                        app.mesDropDown.Items = list;
                    end
           
            end
        end

        % Button pushed function: InstructivoButton
        function InstructivoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MenuTab;
            app.Instructivoapp = Instructivo(app,app.idioma);
            app.InstructivoButton.Enable = "off";
           
        end

        % Button pushed function: DistribucindelVientoButton
        function DistribucindelVientoButtonPushed(app, event)
            app.DistribucindelVientoTab.Parent = app.TabGroup;
            
                    meses = table2cell(app.datos(:,1))
                   
                    x1 = find(startsWith(meses,"ene"));
                    x2 = find(startsWith(meses,"feb"));
                    x3 = find(startsWith(meses,"mar"));
                    x4 = find(startsWith(meses,"abr"));
                    x5 = find(startsWith(meses,"may"));
                    x6 = find(startsWith(meses,"jun"));
                    x7 = find(startsWith(meses,"jul"));
                    x8 = find(startsWith(meses,"ago"));
                    x9 = find(startsWith(meses,"sep"));
                    x10 = find(startsWith(meses,"oct"));
                    x11 = find(startsWith(meses,"nov"));
                    x12 = find(startsWith(meses,"dic"));
                    list = ["(...)"; "Todos los datos"]; ...donde ms es el mes siguiente
                    app.mesDropDown.Items = list;
                    if(isempty(x1)==false) 
                        list = [list; "Enero"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x2)==false)
                        list = [list; "Febrero"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x3)==false)
                        list = [list; "Marzo"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x4)==false)
                        list = [list; "Abril"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x5)==false)
                        list = [list; "Mayo"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x6)==false)
                        list = [list; "Junio"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x7)==false)
                        list = [list; "Julio"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x8)==false)
                        list = [list; "Agosto"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x9)==false)
                        list = [list; "Septiembre"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x10)==false)
                        list = [list; "Octubre"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x11)==false)
                        list = [list; "Noviembre"];
                        app.mesDropDown.Items = list;
                    end
                    if(isempty(x12)==false)
                        list = [list; "Diciembre"];
                        app.mesDropDown.Items = list;
                    end


            app.TabGroup.SelectedTab = app.DistribucindelVientoTab;
            app.Panel_4.Visible = "off";
        end

        % Button pushed function: RosadeVientosButton
        function RosadeVientosButtonPushed(app, event)
            app.RosadevientosTab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.RosadevientosTab;
            app.Panel_4.Visible = "off";
        end

        % Button pushed function: RecursosDisponiblesButton
        function RecursosDisponiblesButtonPushed(app, event)
            app.RecursosdisponiblesTab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.RecursosdisponiblesTab;
            app.Panel_4.Visible = "off";
        end

        % Button pushed function: SugerenciaTurbinaButton
        function SugerenciaTurbinaButtonPushed(app, event)
            app.SugerenciaTurbinaparaAerogeneradorTab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.SugerenciaTurbinaparaAerogeneradorTab;
            app.Panel_4.Visible = "off";
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            value = app.DropDown_7.Value;
            viento = table2array(app.datos(:,app.ws));
            viento = mean(viento(viento~=0));
            
            switch value
                case 'm/s'
                    app.DropDownLabel_7.Text = string(viento);
                case 'km/h'
                    km = viento*3.6;
                    app.DropDownLabel_7.Text = string(km);
                case 'mi/h'
                    mi = viento*2.237;
                    app.DropDownLabel_7.Text = string(mi);
                case 'ft/s'
                    ft = viento*1.467;
                    app.DropDownLabel_7.Text = string(ft);
                    
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea_7.Value = buscador(app, app.idioma, 'presionconcepto');
            app.opcion = "2";
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TextArea_7.Value = buscador(app, app.idioma, 'temperaturacont');
            app.opcion = "4";
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.TextArea_7.Value = buscador(app, app.idioma, 'velocidadcont');
            app.opcion = "1";
        end

        % Button pushed function: Button_5
        function Button_5Pushed2(app, event)
            app.TextArea_7.Value = buscador(app, app.idioma, 'intensidadcont');
            app.opcion = "6";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TextArea_7.Value = buscador(app, app.idioma, 'alturacont');
            app.opcion = "3";
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";    
                switch app.opcion
                            case '1'
                                app.TextArea_7.Value = buscador(app, app.idioma, 'velocidadcont');
                            case '2'
                             app.TextArea_7.Value = buscador(app, app.idioma, 'presionconcepto');
                          case '3'
                             app.TextArea_7.Value = buscador(app, app.idioma, 'alturacont');
                          case '4'
                               app.TextArea_7.Value = buscador(app, app.idioma, 'temperaturacont');
                         case '5'
                              app.TextArea_7.Value = buscador(app, app.idioma, 'densidadcont');
                          case '6'
                              app.TextArea_7.Value = buscador(app, app.idioma, 'intensidadcont');
                end
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
            switch app.opcion
                        case '1'
                            app.TextArea_7.Value = buscador(app, app.idioma, 'velocidadcont');
                        case '2'
                            app.TextArea_7.Value = buscador(app, app.idioma, 'presionconcepto');
                        case '3'
                            app.TextArea_7.Value = buscador(app, app.idioma, 'alturacont');
                        case '4'
                             app.TextArea_7.Value = buscador(app, app.idioma, 'temperaturacont');
                        case '5'
                            app.TextArea_7.Value = buscador(app, app.idioma, 'densidadcont');
                        case '6'
                            app.TextArea_7.Value = buscador(app, app.idioma, 'intensidadcont');
            end

        end

        % Callback function
        function GuardarImagenButtonPushed(app, event)
            try
                filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
                [filename,filepath] = uiputfile(filter);
                exportgraphics(app.ax, fullfile(filepath,filename));
            catch
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcesodeAnlisisUIFigure = uifigure('Visible', 'off');
            app.ProcesodeAnlisisUIFigure.Name = 'Proceso de Análisis';
            app.ProcesodeAnlisisUIFigure.Icon = 'icono.png';
            app.ProcesodeAnlisisUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProcesodeAnlisisUIFigureCloseRequest, true);
            app.IdiomaLanguageMenu = uimenu(app.ProcesodeAnlisisUIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Separator = 'on';
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Separator = 'on';
            app.EspaolMenu.Text = 'Español';
            app.TabGroup = uitabgroup(app.ProcesodeAnlisisUIFigure);
            app.TabGroup.Visible = 'off';
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Panel_5 = uipanel(app.MenuTab);
            app.InstructivoButton = uibutton(app.Panel_5, 'push');
            app.InstructivoButton.ButtonPushedFcn = createCallbackFcn(app, @InstructivoButtonPushed, true);
            app.InstructivoButton.Icon = 'instrucciones.svg';
            app.InstructivoButton.Text = 'Instructivo';
            app.FinalizarprocesoButton = uibutton(app.Panel_5, 'push');
            app.FinalizarprocesoButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarprocesoButtonPushed, true);
            app.FinalizarprocesoButton.Icon = 'off.svg';
            app.FinalizarprocesoButton.Text = 'Finalizar proceso';
            app.AplicacionesPanel = uipanel(app.Panel_5);
            app.AplicacionesPanel.Title = 'Aplicaciones';
            app.Panel_6 = uipanel(app.AplicacionesPanel);
            app.DistribucindelVientoButton = uibutton(app.Panel_6, 'push');
            app.DistribucindelVientoButton.ButtonPushedFcn = createCallbackFcn(app, @DistribucindelVientoButtonPushed, true);
            app.DistribucindelVientoButton.Icon = 'distribucionwhite.svg';
            app.DistribucindelVientoButton.Text = 'Distribución del Viento';
            app.RosadeVientosButton = uibutton(app.Panel_6, 'push');
            app.RosadeVientosButton.ButtonPushedFcn = createCallbackFcn(app, @RosadeVientosButtonPushed, true);
            app.RosadeVientosButton.Icon = 'rosawhite.svg';
            app.RosadeVientosButton.Text = 'Rosa de Vientos';
            app.RecursosDisponiblesButton = uibutton(app.Panel_6, 'push');
            app.RecursosDisponiblesButton.ButtonPushedFcn = createCallbackFcn(app, @RecursosDisponiblesButtonPushed, true);
            app.RecursosDisponiblesButton.Icon = 'recursoswhite.svg';
            app.RecursosDisponiblesButton.Text = 'Recursos Disponibles';
            app.SugerenciaTurbinaButton = uibutton(app.Panel_6, 'push');
            app.SugerenciaTurbinaButton.ButtonPushedFcn = createCallbackFcn(app, @SugerenciaTurbinaButtonPushed, true);
            app.SugerenciaTurbinaButton.Icon = 'turbinawhite.svg';
            app.SugerenciaTurbinaButton.Text = 'Sugerencia Turbina';
            app.DatosbsicosPanel = uipanel(app.MenuTab);
            app.DatosbsicosPanel.Title = 'Datos básicos';
            app.Panel_8 = uipanel(app.DatosbsicosPanel);
            app.NombredelArchivoEditFieldLabel = uilabel(app.Panel_8);
            app.NombredelArchivoEditFieldLabel.Text = 'Nombre del Archivo:';
            app.NombredelArchivoEditField = uieditfield(app.Panel_8, 'text');
            app.NombredelArchivoEditField.Editable = 'off';
            app.WindDirectionColumnaEditFieldLabel = uilabel(app.Panel_8);
            app.WindDirectionColumnaEditFieldLabel.Text = {'Wind Direction'; '[Columna]'};
            app.WindDirectionColumnaEditField = uieditfield(app.Panel_8, 'text');
            app.WindDirectionColumnaEditField.Editable = 'off';
            app.AverageTemperatureColumnaEditFieldLabel = uilabel(app.Panel_8);
            app.AverageTemperatureColumnaEditFieldLabel.Text = {'Average Temperature'; '[Columna]'};
            app.AverageTemperatureColumnaEditField = uieditfield(app.Panel_8, 'text');
            app.AverageTemperatureColumnaEditField.Editable = 'off';
            app.WindSpeedColumnaEditFieldLabel = uilabel(app.Panel_8);
            app.WindSpeedColumnaEditFieldLabel.Text = {'Wind Speed'; '[Columna]'};
            app.WindSpeedColumnaEditField = uieditfield(app.Panel_8, 'text');
            app.WindSpeedColumnaEditField.Editable = 'off';
            app.BarometricPressureColumnaEditFieldLabel = uilabel(app.Panel_8);
            app.BarometricPressureColumnaEditFieldLabel.Text = {'Barometric Pressure'; '[Columna]'};
            app.BarometricPressureColumnaEditField = uieditfield(app.Panel_8, 'text');
            app.BarometricPressureColumnaEditField.Editable = 'off';
            app.Panel_4 = uipanel(app.MenuTab);
            app.DistribucindelVientoTab = uitab(app.TabGroup);
            app.DistribucindelVientoTab.Title = '1. Distribución del Viento';
            app.InstruccionesPanel = uipanel(app.DistribucindelVientoTab);
            app.InstruccionesPanel.Title = 'Instrucciones';
            app.ParmetrosNormalPanel = uipanel(app.DistribucindelVientoTab);
            app.ParmetrosNormalPanel.Title = 'Parámetros Normal ';
            app.ParmetrosWeibullPanel = uipanel(app.DistribucindelVientoTab);
            app.ParmetrosWeibullPanel.Title = 'Parámetros Weibull ';
            app.Panel_7 = uipanel(app.DistribucindelVientoTab);
            app.RecomendadoLabel = uilabel(app.Panel_7);
            app.RecomendadoLabel.Enable = 'off';
            app.RecomendadoLabel.Tooltip = {''};
            app.RecomendadoLabel.Text = 'Recomendado( )';
            app.GraficarButton = uibutton(app.Panel_7, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Enable = 'off';
            app.GraficarButton.Text = '3.Graficar';
            app.mesDropDownLabel = uilabel(app.Panel_7);
            app.mesDropDownLabel.Text = '1. mes';
            app.mesDropDown = uidropdown(app.Panel_7);
            app.mesDropDown.Items = {'(...)', 'Todos los datos', 'Enero', 'Febrero', 'Marzo', 'Abril', 'Mayo', 'Junio', 'Julio', 'Agosto', 'Septiembre', 'Octubre', 'Noviembre', 'Diciembre'};
            app.mesDropDown.ValueChangedFcn = createCallbackFcn(app, @mesDropDownValueChanged, true);
            app.mesDropDown.Value = '(...)';
            app.NmerodebinsDropDownLabel = uilabel(app.Panel_7);
            app.NmerodebinsDropDownLabel.Enable = 'off';
            app.NmerodebinsDropDownLabel.Tooltip = {'Los bins es el rango de números en los que están divididos el eje x en un histograma. Para cada bin, se dibuja una barra en la que el ancho de la barra representa el rango del bin y la altura de la barra representa el número de puntos de datos incluidos en ese rango. '};
            app.NmerodebinsDropDownLabel.Text = '2. Número de bins. (?)';
            app.NmerodebinsDropDown = uidropdown(app.Panel_7);
            app.NmerodebinsDropDown.Items = {'(...)', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '83', '84', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94', '95', '96', '97', '98', '99', '100'};
            app.NmerodebinsDropDown.ValueChangedFcn = createCallbackFcn(app, @NmerodebinsDropDownValueChanged, true);
            app.NmerodebinsDropDown.Enable = 'off';
            app.NmerodebinsDropDown.Value = '(...)';
            app.Label = uilabel(app.Panel_7);
            app.Label.Tooltip = {'Todos los datos : All data'; 'Enero : January'; 'Febrero : February'; 'Marzo : March'; 'Abril : April'; 'Mayo : May'; 'Junio : June'; 'Julio : July'; 'Agosto : August'; 'Septiembre : September'; 'Octubre : October'; 'Noviembre : November'; 'Deciembre : December '};
            app.Label.Text = '(?)';
            app.Label_2 = uilabel(app.Panel_7);
            app.Label_2.Enable = 'off';
            app.Label_2.Tooltip = {''};
            app.Label_2.Text = '( )';
            app.TextArea_5 = uitextarea(app.DistribucindelVientoTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'1. Seleccione el mes que desee conocer su comportamiento o si quiere todos los datos. La aplicación solo mostrara los meses que se encuentren en el archivo.'; ''; '2. Seleccione el número de bines que desea tener la gráfica, el aplicativo ya le recomienda el óptimo.'; ''; '3. Oprima el botón "Gráficar" .'};
            app.Panel_10 = uipanel(app.DistribucindelVientoTab);
            app.EscalamsEditFieldLabel = uilabel(app.Panel_10);
            app.EscalamsEditFieldLabel.Text = 'Escala [m/s] (?)';
            app.EscalamsEditField = uieditfield(app.Panel_10, 'numeric');
            app.EscalamsEditField.ValueDisplayFormat = '%.4f';
            app.EscalamsEditField.Editable = 'off';
            app.kformaEditFieldLabel = uilabel(app.Panel_10);
            app.kformaEditFieldLabel.Text = 'k(forma) (?)';
            app.kformaEditField = uieditfield(app.Panel_10, 'numeric');
            app.kformaEditField.ValueDisplayFormat = '%.4f';
            app.kformaEditField.Editable = 'off';
            app.Panel_9 = uipanel(app.DistribucindelVientoTab);
            app.mumsEditFieldLabel = uilabel(app.Panel_9);
            app.mumsEditFieldLabel.Tooltip = {'Mean (Promedio)'};
            app.mumsEditFieldLabel.Text = ' mu [m/s] (?)';
            app.mumsEditField = uieditfield(app.Panel_9, 'numeric');
            app.mumsEditField.ValueDisplayFormat = '%.4f';
            app.mumsEditField.Editable = 'off';
            app.sigmaEditFieldLabel = uilabel(app.Panel_9);
            app.sigmaEditFieldLabel.Tooltip = {'Mean (Promedio)'};
            app.sigmaEditFieldLabel.Text = ' sigma (?)';
            app.sigmaEditField = uieditfield(app.Panel_9, 'numeric');
            app.sigmaEditField.ValueDisplayFormat = '%.4f';
            app.sigmaEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.DistribucindelVientoTab);
            title(app.UIAxes, 'Gráfico original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.DistribucindelVientoTab);
            title(app.UIAxes2, 'Distribución Normal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.DistribucindelVientoTab);
            title(app.UIAxes3, 'Distribución Weibull')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.RosadevientosTab = uitab(app.TabGroup);
            app.RosadevientosTab.Title = '2. Rosa de vientos    ';
            app.GraficarButton_2 = uibutton(app.RosadevientosTab, 'push');
            app.GraficarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_2Pushed, true);
            app.GraficarButton_2.Text = 'Graficar';
            app.Image = uiimage(app.RosadevientosTab);
            app.Image.ImageSource = 'compass.svg';
            app.DistribucindelvientoPanel = uipanel(app.RosadevientosTab);
            app.DistribucindelvientoPanel.Title = 'Distribución del viento';
            app.Panel_11 = uipanel(app.DistribucindelvientoPanel);
            app.RecursosdisponiblesTab = uitab(app.TabGroup);
            app.RecursosdisponiblesTab.Title = '3. Recursos disponibles  ';
            app.VariablesPanel = uipanel(app.RecursosdisponiblesTab);
            app.VariablesPanel.Title = 'Variables';
            app.Panel_12 = uipanel(app.VariablesPanel);
            app.PresinpromedioLabel = uilabel(app.Panel_12);
            app.PresinpromedioLabel.Text = 'Presión promedio';
            app.AlturasobreelniveldelmarLabel = uilabel(app.Panel_12);
            app.AlturasobreelniveldelmarLabel.Text = 'Altura sobre el nivel del mar';
            app.TemperaturapromedioLabel = uilabel(app.Panel_12);
            app.TemperaturapromedioLabel.Text = 'Temperatura promedio';
            app.DensidaddelaireLabel = uilabel(app.Panel_12);
            app.DensidaddelaireLabel.Text = 'Densidad del aire';
            app.IntensidadLabel = uilabel(app.Panel_12);
            app.IntensidadLabel.Text = 'Intensidad';
            app.Button = uibutton(app.Panel_12, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.Panel_12, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.Panel_12, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.Button_4 = uibutton(app.Panel_12, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_4.Text = '?';
            app.DropDown_7 = uidropdown(app.Panel_12);
            app.DropDown_7.Items = {'m/s', 'km/h', 'mi/h', 'ft/s'};
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Enable = 'off';
            app.DropDown_7.Value = 'm/s';
            app.VelocidadpromedioLabel = uilabel(app.Panel_12);
            app.VelocidadpromedioLabel.Text = 'Velocidad promedio';
            app.Button_6 = uibutton(app.Panel_12, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.DropDownLabel_7 = uilabel(app.Panel_12);
            app.DropDownLabel_7.Text = '';
            app.DropDownLabel = uilabel(app.Panel_12);
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.Panel_12);
            app.DropDown.Items = {'mmHg', 'mbar', 'atm', 'psi', 'hPa'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'mmHg';
            app.DropDown_2Label = uilabel(app.Panel_12);
            app.DropDown_2Label.Text = '';
            app.DropDown_2 = uidropdown(app.Panel_12);
            app.DropDown_2.Items = {'m.s.n.m'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'm.s.n.m';
            app.DropDown_3Label = uilabel(app.Panel_12);
            app.DropDown_3Label.Text = '';
            app.DropDown_3 = uidropdown(app.Panel_12);
            app.DropDown_3.Items = {'° C', '° F', 'K'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = '° C';
            app.DropDown_4Label = uilabel(app.Panel_12);
            app.DropDown_4Label.Text = '';
            app.DropDown_4 = uidropdown(app.Panel_12);
            app.DropDown_4.Items = {'Kg/m^3', 'lb/gal', 'lb/ft^3'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Enable = 'off';
            app.DropDown_4.Value = 'Kg/m^3';
            app.DropDown_5Label = uilabel(app.Panel_12);
            app.DropDown_5Label.Text = '';
            app.DropDown_5 = uidropdown(app.Panel_12);
            app.DropDown_5.Items = {'W/m^2'};
            app.DropDown_5.Enable = 'off';
            app.DropDown_5.Value = 'W/m^2';
            app.CalcularButton = uibutton(app.Panel_12, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Button_5 = uibutton(app.Panel_12, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed2, true);
            app.Button_5.Tooltip = {''};
            app.Button_5.Text = '?';
            app.Image2 = uiimage(app.RecursosdisponiblesTab);
            app.Image2.ImageSource = 'iconogrande.svg';
            app.DescripcinPanel = uipanel(app.RecursosdisponiblesTab);
            app.DescripcinPanel.Title = 'Descripción';
            app.Panel_13 = uipanel(app.DescripcinPanel);
            app.TextArea_7 = uitextarea(app.Panel_13);
            app.SugerenciaTurbinaparaAerogeneradorTab = uitab(app.TabGroup);
            app.SugerenciaTurbinaparaAerogeneradorTab.Title = '4. Sugerencia Turbina para Aerogenerador';
            app.UITable = uitable(app.SugerenciaTurbinaparaAerogeneradorTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ComprobarButton = uibutton(app.SugerenciaTurbinaparaAerogeneradorTab, 'push');
            app.ComprobarButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed, true);
            app.ComprobarButton.Text = 'Comprobar';
            % Show the figure after all components are created
            app.ProcesodeAnlisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proceso(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcesodeAnlisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcesodeAnlisisUIFigure)
        end
    end
end