classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingsUIFigure              matlab.ui.Figure
        IdiomaLanguageMenu            matlab.ui.container.Menu
        EnglishMenu                   matlab.ui.container.Menu
        EspaolMenu                    matlab.ui.container.Menu
        ConfiguracindelarchivoPanel   matlab.ui.container.Panel
        Panel_4                       matlab.ui.container.Panel
        SeleccionelavariableButtonGroup  matlab.ui.container.ButtonGroup
        ButtonGroup                   matlab.ui.container.ButtonGroup
        WSWindSpeedButton             matlab.ui.control.RadioButton
        WDWindDirectionButton         matlab.ui.control.RadioButton
        BPBarometricPressureButton    matlab.ui.control.RadioButton
        ATAverageTemperatureButton    matlab.ui.control.RadioButton
        Button_5                      matlab.ui.control.RadioButton
        ConfiguracinestablecidaPanel  matlab.ui.container.Panel
        Panel_3                       matlab.ui.container.Panel
        VelocidaddelvientoWindSpeedWSLabel  matlab.ui.control.Label
        DireccindelvientoWindDirectionWDLabel  matlab.ui.control.Label
        PresinAtmsfericaBarometricpressureBPLabel  matlab.ui.control.Label
        TemperaturapromedioAverageTemperatureATLabel  matlab.ui.control.Label
        ColumnaLabel_2                matlab.ui.control.Label
        ColumnaEditField              matlab.ui.control.NumericEditField
        ColumnaLabel_4                matlab.ui.control.Label
        ColumnaEditField_4            matlab.ui.control.NumericEditField
        ColumnaLabel_3                matlab.ui.control.Label
        ColumnaEditField_2            matlab.ui.control.NumericEditField
        ColumnaLabel                  matlab.ui.control.Label
        ColumnaEditField_3            matlab.ui.control.NumericEditField
        UnidadesDropDownLabel         matlab.ui.control.Label
        UnidadesDropDown              matlab.ui.control.DropDown
        UnidadesDropDown_2Label       matlab.ui.control.Label
        UnidadesDropDown_2            matlab.ui.control.DropDown
        UnidadesDropDown_3Label       matlab.ui.control.Label
        UnidadesDropDown_3            matlab.ui.control.DropDown
        UnidadesDropDown_4Label       matlab.ui.control.Label
        UnidadesDropDown_4            matlab.ui.control.DropDown
        NombredelacolumnaEditField_2Label  matlab.ui.control.Label
        NombredelacolumnaEditField_2  matlab.ui.control.EditField
        NombredelacolumnaEditField_3Label  matlab.ui.control.Label
        NombredelacolumnaEditField_3  matlab.ui.control.EditField
        NombredelacolumnaEditField_4Label  matlab.ui.control.Label
        NombredelacolumnaEditField_4  matlab.ui.control.EditField
        NombredelacolumnaEditFieldLabel  matlab.ui.control.Label
        NombredelacolumnaEditField    matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_4                        matlab.ui.control.Lamp
        InstruccionesPanel            matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        Panel                         matlab.ui.container.Panel
        Panel_2                       matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        GuardarconfiguracinButton     matlab.ui.control.Button
        SalirButton                   matlab.ui.control.Button
        HabilitePanel                 matlab.ui.container.Panel
        CheckBox                      matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
    end

    %     AUTHORS
%
%     [1] John Jairo Guerrero Moreno
%     [2] Diego Julián Rodríguez Patarroyo
%     [3] Julián Andrés Salamanca Bernal
%
%     Universidad Distrital Francisco José de Caldas
%
%    Facultad de Ingeniería
%    Proyecto Curricular Ingeniería Eléctrica
%    [1,2] Laboratorio de Investigación de Fuentes Alternativas de Energía - LIFAE
%    [3] Grupo de Física e Informática - FISINFOR
%    Emails:
%    [1] jjguerrerom@correo.udistrital.edu.co,
%    [2] djrodriguezp@udistrital.edu.co,
%    [3] jasalamanca@udistrital.edu.co
%    December 1st, 2020


%COPYRIGHT
%
%     This file is part of SCWindDesign.
%     SCWindDesign is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
%
%     SCWindDesign is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
%
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    
    
    properties (Access = private)
        CallingApp; % objeto para poder llamar los objetos de la app menu
        datosapp;
        ws;
        wd;
        bp;
        at;
        uws;
        uwd;
        ubp;
        uat;
        bar1;
        texto1;
        idioma;
    end
    
    methods (Access = private)
        
         function guardaridiomas(app,idioma)
            
            app.ConfiguracindelarchivoPanel.Title = buscador(app, idioma, 'settings');
            app.InstruccionesPanel.Title = buscador(app, idioma, 'instrucciones');
            app.SeleccionelavariableButtonGroup.Title = buscador(app, idioma, 'seleccionpanel');
            app.TextArea.Value = buscador(app, idioma, 'pasos');
            app.Panel.Title = buscador(app, idioma, 'vista');
            app.ConfiguracinestablecidaPanel.Title = buscador(app, idioma, 'confset');
            app.NombredelacolumnaEditField_2Label.Text = buscador(app, idioma, 'nomcol');
            app.NombredelacolumnaEditField_3Label.Text = buscador(app, idioma, 'nomcol');
            app.NombredelacolumnaEditField_4Label.Text = buscador(app, idioma, 'nomcol');
            app.NombredelacolumnaEditFieldLabel.Text = buscador(app, idioma, 'nomcol');
            app.ColumnaLabel.Text = buscador(app, idioma, 'col');
            app.ColumnaLabel_2.Text = buscador(app, idioma, 'col');
            app.ColumnaLabel_3.Text = buscador(app, idioma, 'col');
            app.ColumnaLabel_4.Text = buscador(app, idioma, 'col');
            app.UnidadesDropDownLabel.Text = buscador(app, idioma, 'unidades');
            app.UnidadesDropDown_2Label.Text = buscador(app, idioma, 'unidades');
            app.UnidadesDropDown_3Label.Text = buscador(app, idioma, 'unidades');
            app.UnidadesDropDown_4Label.Text = buscador(app, idioma, 'unidades');
            app.SalirButton.Text = buscador(app, idioma, 'salir');
            app.GuardarconfiguracinButton.Text = buscador(app, idioma, 'guardarconf');


         end
         
         function  Idioma = buscador(app, idioma, texto)
              
             
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
      
         end
         
         function verificacion(app)
             
             if app.ColumnaEditField.Value ~=0 
                 app.CheckBox.Value = 1;
                 CheckBoxValueChanged(app);   
             end
             if app.ColumnaEditField_2.Value ~=0 
                 app.CheckBox_2.Value = 1;
                 CheckBox_2ValueChanged(app);   
             end
             if app.ColumnaEditField_3.Value ~=0 
                 app.CheckBox_3.Value = 1;
                 CheckBox_3ValueChanged(app);   
             end
             if app.ColumnaEditField_4.Value ~=0 
                 app.CheckBox_4.Value = 1;
                 CheckBox_4ValueChanged(app);   
             end
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datos, ws, wd, bp, at, uws, uwd, ubp, uat, nombrews, nombrewd, nombrebp, nombreat, idioma)
            app.CallingApp = mainapp; ...app.UITable.Data = [datos.]
            app.ColumnaEditField.Value = ws;
            app.ColumnaEditField_2.Value = wd;
            app.ColumnaEditField_3.Value = bp;
            app.ColumnaEditField_4.Value = at;
            app.UnidadesDropDown.Value = uws;
            app.UnidadesDropDown_2.Value = uwd;
            app.UnidadesDropDown_3.Value = ubp;
            app.UnidadesDropDown_4.Value = uat;
            app.NombredelacolumnaEditField_2.Value = nombrews;
            app.NombredelacolumnaEditField_3.Value = nombrewd;
            app.NombredelacolumnaEditField_4.Value = nombrebp;
            app.NombredelacolumnaEditField.Value = nombreat;
            app.UITable.Data = datos(1:7,:);
            app.datosapp = datos;
            app.idioma = idioma;
             if idioma == "Español"
                app.EspaolMenu.Checked = "on";
                app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end            
            app.UITable.ColumnName = datos.Properties.VariableNames;
            if  app.ColumnaEditField_4.Value ~= 0
                app.SalirButton.Enable = "on";
            end
            app.ConfiguracinestablecidaPanel.Visible = "on";
            verificacion(app);
        end

        % Close request function: SettingsUIFigure
        function SettingsUIFigureCloseRequest(app, event)
            uialert(app.SettingsUIFigure, buscador(app, app.idioma, 'aviso') , buscador(app, app.idioma, 'notice'));
            
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            
            app.bar1 = [0 1 1 0];
            app.texto1 = sprintf('%.0f%%',100);
            app.CallingApp.SeleccionarVariablesButton.Text = buscador(app, app.idioma, 'modificarseleccion');
            app.CallingApp.SeleccionarVariablesButton.Enable = "on";
            app.CallingApp.ComenzarprocesoButton.Visible = "on";
            actualizar1(app.CallingApp,app.bar1,app.texto1);
            app.CallingApp.UIAxes.Title.String = buscador(app, app.idioma, 'axestitulo3');
            delete(app)            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if app.WSWindSpeedButton.Value == true && app.CheckBox.Value == 1
               ... uialert(app.SettingsUIFigure,sprintf('%d,%d',indices(1,1),indices(1,2)),"celda");
                app.ColumnaEditField.Value = str2double(sprintf('%d',indices(1,2)));
                app.NombredelacolumnaEditField_2.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
                
            end
            if app.WDWindDirectionButton.Value == true && app.CheckBox_2.Value == 1
                app.ColumnaEditField_2.Value = str2double(sprintf('%d',indices(1,2)));
                app.NombredelacolumnaEditField_3.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
            if app.BPBarometricPressureButton.Value == true && app.CheckBox_3.Value == 1
                app.ColumnaEditField_3.Value = str2double(sprintf('%d',indices(1,2)));
                app.NombredelacolumnaEditField_4.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
            if app.ATAverageTemperatureButton.Value == true && app.CheckBox_4.Value == 1
                app.ColumnaEditField_4.Value = str2double(sprintf('%d',indices(1,2)));
                app.NombredelacolumnaEditField.Value = string(app.datosapp.Properties.VariableNames(1,indices(1,2)));
            end
            
        end

        % Button pushed function: GuardarconfiguracinButton
        function GuardarconfiguracinButtonPushed(app, event)


%            if app.ColumnaEditField.Value ~= 0 && app.ColumnaEditField_2.Value ~= 0 && app.ColumnaEditField_3.Value ~= 0 && app.ColumnaEditField_4.Value ~= 0 && app.UnidadesDropDown.Value ~= "(...)" && app.UnidadesDropDown_2.Value ~= "(...)" && app.UnidadesDropDown_3.Value ~= "(...)" && app.UnidadesDropDown_4.Value  ~= "(...)"                
            if (app.ColumnaEditField.Value == 0 && app.CheckBox.Value == 1) || ( app.ColumnaEditField_2.Value == 0 && app.CheckBox_2.Value == 1 ) ||  (app.ColumnaEditField_3.Value == 0 && app.CheckBox_3.Value == 1) || (app.ColumnaEditField_4.Value == 0 && app.CheckBox_4.Value == 1) || (app.UnidadesDropDown.Value == "(...)" && app.CheckBox.Value == 1) || (app.UnidadesDropDown_2.Value == "(...)" && app.CheckBox_2.Value == 1) || (app.UnidadesDropDown_3.Value == "(...)"&& app.CheckBox_3.Value == 1) || (app.UnidadesDropDown_4.Value == "(...)" && app.CheckBox_4.Value == 1)
                uialert(app.SettingsUIFigure, buscador(app, app.idioma,'verificar'), 'Error');
            else
                
                if (app.ColumnaEditField.Value ~= 0 && app.CheckBox.Value == 1) || ( app.ColumnaEditField_2.Value ~= 0 && app.CheckBox_2.Value == 1 ) ||  (app.ColumnaEditField_3.Value ~= 0 && app.CheckBox_3.Value == 1) || (app.ColumnaEditField_4.Value ~= 0 && app.CheckBox_4.Value == 1) || (app.UnidadesDropDown.Value == "(...)" && app.CheckBox.Value == 1) || (app.UnidadesDropDown_2.Value == "(...)" && app.CheckBox_2.Value == 1) || (app.UnidadesDropDown_3.Value == "(...)"&& app.CheckBox_3.Value == 1) || (app.UnidadesDropDown_4.Value == "(...)" && app.CheckBox_4.Value == 1)
                actualizar(app.CallingApp,app.ColumnaEditField.Value,app.ColumnaEditField_2.Value,app.ColumnaEditField_3.Value,app.ColumnaEditField_4.Value,app.UnidadesDropDown.Value,app.UnidadesDropDown_2.Value,app.UnidadesDropDown_3.Value,app.UnidadesDropDown_4.Value,app.NombredelacolumnaEditField_2.Value,app.NombredelacolumnaEditField_3.Value,app.NombredelacolumnaEditField_4.Value,app.NombredelacolumnaEditField.Value);
                uialert(app.SettingsUIFigure, buscador(app, app.idioma, 'savedata') , buscador(app, app.idioma, 'informacion') ,'Icon','success');
                app.SalirButton.Enable = "on";
                
                else
                uialert(app.SettingsUIFigure, buscador(app, app.idioma, 'vacio'), buscador(app, app.idioma, 'informacion'), 'Icon', "info");
                    
                end
            end
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            switch value
                case 1
                    app.WSWindSpeedButton.Enable = "on";
                    app.VelocidaddelvientoWindSpeedWSLabel.Enable = "on";
                    app.NombredelacolumnaEditField_2.Enable = "on";
                    app.NombredelacolumnaEditField_2Label.Enable= "on";
                    app.ColumnaEditField.Enable = "on";
                    app.ColumnaLabel_2.Enable = "on";
                    app.UnidadesDropDown.Enable = "on";
                    app.UnidadesDropDownLabel.Enable = "on";
                    
                case 0
                    app.WSWindSpeedButton.Enable = "off";
                    app.VelocidaddelvientoWindSpeedWSLabel.Enable = "off";
                    app.NombredelacolumnaEditField_2.Enable = "off";
                    app.NombredelacolumnaEditField_2.Value = "";
                    app.NombredelacolumnaEditField_2Label.Enable= "off";
                    app.ColumnaEditField.Enable = "off";
                    app.ColumnaEditField.Value = 0;
                    app.ColumnaLabel_2.Enable = "off";
                    app.UnidadesDropDown.Enable = "off";
                    app.UnidadesDropDown.Value = "(...)";
                    app.UnidadesDropDownLabel.Enable = "off";
            end
            
            if app.CheckBox.Value == 0 && app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0 && app.CheckBox_4.Value == 0
                
                app.Button_5.Value = 1;
                
            end
            if app.Button_5.Value == true
                app.Lamp.Color = [1 1 1];
                app.Lamp_2.Color = [1 1 1];
                app.Lamp_3.Color = [1 1 1];
                app.Lamp_4.Color = [1 1 1];
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            switch value
                case 1
                    app.WDWindDirectionButton.Enable = "on";
                    app.DireccindelvientoWindDirectionWDLabel.Enable = "on";
                    app.NombredelacolumnaEditField_3.Enable = "on";
                    app.NombredelacolumnaEditField_3Label.Enable = "on";
                    app.ColumnaEditField_2.Enable = "on";
                    app.ColumnaLabel_3.Enable = "on";
                    app.UnidadesDropDown_2.Enable = "on";
                    app.UnidadesDropDown_2Label.Enable = "on";
                    
                case 0
                    app.WDWindDirectionButton.Enable = "off";
                    app.WDWindDirectionButton.Enable = "off";
                    app.DireccindelvientoWindDirectionWDLabel.Enable = "off";
                    app.NombredelacolumnaEditField_3.Enable = "off";
                    app.NombredelacolumnaEditField_3.Value = "";
                    app.NombredelacolumnaEditField_3Label.Enable = "off";
                    app.ColumnaEditField_2.Enable = "off";
                    app.ColumnaEditField_2.Value = 0;
                    app.ColumnaLabel_3.Enable = "off";
                    app.UnidadesDropDown_2.Enable = "off";
                    app.UnidadesDropDown_2.Value = "(...)";
                    app.UnidadesDropDown_2Label.Enable = "off";
            end
            
            if app.CheckBox.Value == 0 && app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0 && app.CheckBox_4.Value == 0
                
                app.Button_5.Value = 1;
                
            end
            if app.Button_5.Value == true
                app.Lamp.Color = [1 1 1];
                app.Lamp_2.Color = [1 1 1];
                app.Lamp_3.Color = [1 1 1];
                app.Lamp_4.Color = [1 1 1];
            end
            
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            switch value
                case 1
                    app.BPBarometricPressureButton.Enable = "on";
                    app.PresinAtmsfericaBarometricpressureBPLabel.Enable = "on";
                    app.NombredelacolumnaEditField_4.Enable = "on";
                    app.NombredelacolumnaEditField_4Label.Enable = "on";
                    app.ColumnaEditField_3.Enable = "on";
                    app.ColumnaLabel.Enable = "on";
                    app.UnidadesDropDown_3.Enable = "on";
                    app.UnidadesDropDown_3Label.Enable = "on";
                    
                case 0
                    app.BPBarometricPressureButton.Enable = "off";
                    app.PresinAtmsfericaBarometricpressureBPLabel.Enable = "off";
                    app.NombredelacolumnaEditField_4.Enable = "off";
                    app.NombredelacolumnaEditField_4.Value = "";
                    app.NombredelacolumnaEditField_4Label.Enable = "off";
                    app.ColumnaEditField_3.Enable = "off";
                    app.ColumnaEditField_3.Value = 0;
                    app.ColumnaLabel.Enable = "off";
                    app.UnidadesDropDown_3.Enable = "off";
                    app.UnidadesDropDown_3.Value = "(...)";
                    app.UnidadesDropDown_3Label.Enable = "off";
            end
            
            if app.CheckBox.Value == 0 && app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0 && app.CheckBox_4.Value == 0
                
                app.Button_5.Value = 1;
                
            end
            if app.Button_5.Value == true
                app.Lamp.Color = [1 1 1];
                app.Lamp_2.Color = [1 1 1];
                app.Lamp_3.Color = [1 1 1];
                app.Lamp_4.Color = [1 1 1];
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            switch value
                case 1
                    app.ATAverageTemperatureButton.Enable = "on";
                    app.TemperaturapromedioAverageTemperatureATLabel.Enable = "on";
                    app.NombredelacolumnaEditField.Enable = "on";
                    app.NombredelacolumnaEditFieldLabel.Enable = "on";
                    app.ColumnaEditField_4.Enable = "on";
                    app.ColumnaLabel_4.Enable = "on";
                    app.UnidadesDropDown_4.Enable = "on";
                    app.UnidadesDropDown_4Label.Enable = "on";
                    
                case 0
                    app.ATAverageTemperatureButton.Enable = "off";
                    app.TemperaturapromedioAverageTemperatureATLabel.Enable = "off";
                    app.NombredelacolumnaEditField.Enable = "off";
                    app.NombredelacolumnaEditField.Value = "";
                    app.NombredelacolumnaEditFieldLabel.Enable = "off";
                    app.ColumnaEditField_4.Enable = "off";
                    app.ColumnaEditField_4.Value = 0;
                    app.ColumnaLabel_4.Enable = "off";
                    app.UnidadesDropDown_4.Enable = "off";
                    app.UnidadesDropDown_4.Value = "(...)";
                    app.UnidadesDropDown_4Label.Enable = "off";
            end
            
            if app.CheckBox.Value == 0 && app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0 && app.CheckBox_4.Value == 0
                
                app.Button_5.Value = 1;
                
                
            end
            if app.Button_5.Value == true
                app.Lamp.Color = [1 1 1];
                app.Lamp_2.Color = [1 1 1];
                app.Lamp_3.Color = [1 1 1];
                app.Lamp_4.Color = [1 1 1];
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject
    
            switch selectedButton.Text
                
                case 'WS - Wind Speed'
                    
                    app.Lamp.Color = [0.00 0.00 1.00];
                    app.Lamp_2.Color = [1 1 1];
                    app.Lamp_3.Color = [1 1 1];
                    app.Lamp_4.Color = [1 1 1];
                    app.VelocidaddelvientoWindSpeedWSLabel.FontColor = [0.00 0.00 1.00];
                    app.DireccindelvientoWindDirectionWDLabel.FontColor = [0 0.00 0.00];
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontColor =  [0 0 0];
                    app.TemperaturapromedioAverageTemperatureATLabel.FontColor = [0 0 0];
                    
                    app.VelocidaddelvientoWindSpeedWSLabel.FontWeight = "bold";
                    app.DireccindelvientoWindDirectionWDLabel.FontWeight = "normal";
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontWeight  =  "normal";
                    app.TemperaturapromedioAverageTemperatureATLabel.FontWeight = "normal";


                case 'WD - Wind Direction'
                    
                    app.Lamp.Color = [1 1 1];
                    app.Lamp_2.Color = [1.00 0.00 0.00];
                    app.Lamp_3.Color = [1 1 1];
                    app.Lamp_4.Color = [1 1 1];
                    app.DireccindelvientoWindDirectionWDLabel.FontColor = [1.00 0.00 0.00];
                    app.VelocidaddelvientoWindSpeedWSLabel.FontColor = [0.00 0.00 0.00];
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontColor =  [0 0 0];
                    app.TemperaturapromedioAverageTemperatureATLabel.FontColor = [0 0 0];
                    
                    app.VelocidaddelvientoWindSpeedWSLabel.FontWeight = "normal";
                    app.DireccindelvientoWindDirectionWDLabel.FontWeight = "bold";
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontWeight  =  "normal";
                    app.TemperaturapromedioAverageTemperatureATLabel.FontWeight = "normal";
                    
                case 'BP - Barometric Pressure'
                    
                    app.Lamp.Color = [1 1 1];
                    app.Lamp_2.Color = [1 1 1];
                    app.Lamp_3.Color = [0.72 0.27 1.00];
                    app.Lamp_4.Color = [1 1 1];
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontColor =  [0.72 0.27 1.00];
                    app.VelocidaddelvientoWindSpeedWSLabel.FontColor = [0.00 0.00 0.00];
                    app.DireccindelvientoWindDirectionWDLabel.FontColor = [0 0.00 0.00];
                    app.TemperaturapromedioAverageTemperatureATLabel.FontColor = [0 0 0];
                    
                    app.VelocidaddelvientoWindSpeedWSLabel.FontWeight = "normal";
                    app.DireccindelvientoWindDirectionWDLabel.FontWeight = "normal";
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontWeight  =  "bold";
                    app.TemperaturapromedioAverageTemperatureATLabel.FontWeight = "normal";
                    
                    
                case 'AT- Average Temperature'
                    
                    app.Lamp.Color = [1 1 1];
                    app.Lamp_2.Color = [1 1 1];
                    app.Lamp_3.Color = [1 1 1];
                    app.Lamp_4.Color = [0.35 0.82 0.87];
                    app.TemperaturapromedioAverageTemperatureATLabel.FontColor = [0.35 0.82 0.87];
                    app.VelocidaddelvientoWindSpeedWSLabel.FontColor = [0.00 0.00 0.00];
                    app.DireccindelvientoWindDirectionWDLabel.FontColor = [0 0.00 0.00];
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontColor =  [0 0 0];
                    
                    app.VelocidaddelvientoWindSpeedWSLabel.FontWeight = "normal";
                    app.DireccindelvientoWindDirectionWDLabel.FontWeight = "normal";
                    app.PresinAtmsfericaBarometricpressureBPLabel.FontWeight  =  "normal";
                    app.TemperaturapromedioAverageTemperatureATLabel.FontWeight = "bold";


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingsUIFigure = uifigure('Visible', 'off');
            app.SettingsUIFigure.Name = 'Settings';
            app.SettingsUIFigure.Icon = 'icono.png';
            app.SettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SettingsUIFigureCloseRequest, true);
            app.IdiomaLanguageMenu = uimenu(app.SettingsUIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Separator = 'on';
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Separator = 'on';
            app.EspaolMenu.Text = 'Español';
            app.ConfiguracindelarchivoPanel = uipanel(app.SettingsUIFigure);
            app.ConfiguracindelarchivoPanel.Title = 'Configuración del archivo';
            app.Panel_4 = uipanel(app.ConfiguracindelarchivoPanel);
            app.SeleccionelavariableButtonGroup = uibuttongroup(app.Panel_4);
            app.SeleccionelavariableButtonGroup.Tooltip = {''};
            app.SeleccionelavariableButtonGroup.Title = ' Seleccione la variable';
            app.ButtonGroup = uibuttongroup(app.SeleccionelavariableButtonGroup);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.WSWindSpeedButton = uiradiobutton(app.ButtonGroup);
            app.WSWindSpeedButton.Enable = 'off';
            app.WSWindSpeedButton.Text = 'WS - Wind Speed';
            app.WDWindDirectionButton = uiradiobutton(app.ButtonGroup);
            app.WDWindDirectionButton.Enable = 'off';
            app.WDWindDirectionButton.Text = 'WD - Wind Direction';
            app.BPBarometricPressureButton = uiradiobutton(app.ButtonGroup);
            app.BPBarometricPressureButton.Enable = 'off';
            app.BPBarometricPressureButton.Text = 'BP - Barometric Pressure';
            app.ATAverageTemperatureButton = uiradiobutton(app.ButtonGroup);
            app.ATAverageTemperatureButton.Enable = 'off';
            app.ATAverageTemperatureButton.Text = 'AT- Average Temperature';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Visible = 'off';
            app.Button_5.Text = 'Button';
            app.Button_5.Value = true;
            app.ConfiguracinestablecidaPanel = uipanel(app.Panel_4);
            app.ConfiguracinestablecidaPanel.Title = 'Configuración establecida';
            app.Panel_3 = uipanel(app.ConfiguracinestablecidaPanel);
            app.VelocidaddelvientoWindSpeedWSLabel = uilabel(app.Panel_3);
            app.VelocidaddelvientoWindSpeedWSLabel.Enable = 'off';
            app.VelocidaddelvientoWindSpeedWSLabel.Text = 'Velocidad del viento - Wind Speed (WS)                          ';
            app.DireccindelvientoWindDirectionWDLabel = uilabel(app.Panel_3);
            app.DireccindelvientoWindDirectionWDLabel.Enable = 'off';
            app.DireccindelvientoWindDirectionWDLabel.Text = 'Dirección del viento - Wind Direction (WD)                      ';
            app.PresinAtmsfericaBarometricpressureBPLabel = uilabel(app.Panel_3);
            app.PresinAtmsfericaBarometricpressureBPLabel.Enable = 'off';
            app.PresinAtmsfericaBarometricpressureBPLabel.Text = 'Presión Atmósferica - Barometric pressure (BP)           ';
            app.TemperaturapromedioAverageTemperatureATLabel = uilabel(app.Panel_3);
            app.TemperaturapromedioAverageTemperatureATLabel.Enable = 'off';
            app.TemperaturapromedioAverageTemperatureATLabel.Text = 'Temperatura promedio - Average Temperature (AT)      ';
            app.ColumnaLabel_2 = uilabel(app.Panel_3);
            app.ColumnaLabel_2.Enable = 'off';
            app.ColumnaLabel_2.Text = '# Columna';
            app.ColumnaEditField = uieditfield(app.Panel_3, 'numeric');
            app.ColumnaEditField.Editable = 'off';
            app.ColumnaEditField.Enable = 'off';
            app.ColumnaLabel_4 = uilabel(app.Panel_3);
            app.ColumnaLabel_4.Enable = 'off';
            app.ColumnaLabel_4.Text = '# Columna';
            app.ColumnaEditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.ColumnaEditField_4.Editable = 'off';
            app.ColumnaEditField_4.Enable = 'off';
            app.ColumnaLabel_3 = uilabel(app.Panel_3);
            app.ColumnaLabel_3.Enable = 'off';
            app.ColumnaLabel_3.Text = '# Columna';
            app.ColumnaEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.ColumnaEditField_2.Editable = 'off';
            app.ColumnaEditField_2.Enable = 'off';
            app.ColumnaLabel = uilabel(app.Panel_3);
            app.ColumnaLabel.Enable = 'off';
            app.ColumnaLabel.Text = '# Columna';
            app.ColumnaEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.ColumnaEditField_3.Editable = 'off';
            app.ColumnaEditField_3.Enable = 'off';
            app.UnidadesDropDownLabel = uilabel(app.Panel_3);
            app.UnidadesDropDownLabel.Enable = 'off';
            app.UnidadesDropDownLabel.Text = 'Unidades';
            app.UnidadesDropDown = uidropdown(app.Panel_3);
            app.UnidadesDropDown.Items = {'(...)', 'm/s'};
            app.UnidadesDropDown.Enable = 'off';
            app.UnidadesDropDown.Value = '(...)';
            app.UnidadesDropDown_2Label = uilabel(app.Panel_3);
            app.UnidadesDropDown_2Label.Enable = 'off';
            app.UnidadesDropDown_2Label.Text = 'Unidades';
            app.UnidadesDropDown_2 = uidropdown(app.Panel_3);
            app.UnidadesDropDown_2.Items = {'(...)', 'Grados °'};
            app.UnidadesDropDown_2.Enable = 'off';
            app.UnidadesDropDown_2.Value = '(...)';
            app.UnidadesDropDown_3Label = uilabel(app.Panel_3);
            app.UnidadesDropDown_3Label.Enable = 'off';
            app.UnidadesDropDown_3Label.Text = 'Unidades';
            app.UnidadesDropDown_3 = uidropdown(app.Panel_3);
            app.UnidadesDropDown_3.Items = {'(...)', 'mmHg'};
            app.UnidadesDropDown_3.Enable = 'off';
            app.UnidadesDropDown_3.Value = '(...)';
            app.UnidadesDropDown_4Label = uilabel(app.Panel_3);
            app.UnidadesDropDown_4Label.Enable = 'off';
            app.UnidadesDropDown_4Label.Text = 'Unidades';
            app.UnidadesDropDown_4 = uidropdown(app.Panel_3);
            app.UnidadesDropDown_4.Items = {'(...)', '° C'};
            app.UnidadesDropDown_4.Enable = 'off';
            app.UnidadesDropDown_4.Value = '(...)';
            app.NombredelacolumnaEditField_2Label = uilabel(app.Panel_3);
            app.NombredelacolumnaEditField_2Label.Enable = 'off';
            app.NombredelacolumnaEditField_2Label.Text = 'Nombre de la columna';
            app.NombredelacolumnaEditField_2 = uieditfield(app.Panel_3, 'text');
            app.NombredelacolumnaEditField_2.Editable = 'off';
            app.NombredelacolumnaEditField_2.Enable = 'off';
            app.NombredelacolumnaEditField_3Label = uilabel(app.Panel_3);
            app.NombredelacolumnaEditField_3Label.Enable = 'off';
            app.NombredelacolumnaEditField_3Label.Text = 'Nombre de la columna';
            app.NombredelacolumnaEditField_3 = uieditfield(app.Panel_3, 'text');
            app.NombredelacolumnaEditField_3.Editable = 'off';
            app.NombredelacolumnaEditField_3.Enable = 'off';
            app.NombredelacolumnaEditField_4Label = uilabel(app.Panel_3);
            app.NombredelacolumnaEditField_4Label.Enable = 'off';
            app.NombredelacolumnaEditField_4Label.Text = 'Nombre de la columna';
            app.NombredelacolumnaEditField_4 = uieditfield(app.Panel_3, 'text');
            app.NombredelacolumnaEditField_4.Editable = 'off';
            app.NombredelacolumnaEditField_4.Enable = 'off';
            app.NombredelacolumnaEditFieldLabel = uilabel(app.Panel_3);
            app.NombredelacolumnaEditFieldLabel.Enable = 'off';
            app.NombredelacolumnaEditFieldLabel.Text = 'Nombre de  la columna';
            app.NombredelacolumnaEditField = uieditfield(app.Panel_3, 'text');
            app.NombredelacolumnaEditField.Editable = 'off';
            app.NombredelacolumnaEditField.Enable = 'off';
            app.Lamp = uilamp(app.Panel_3);
            app.Lamp_2 = uilamp(app.Panel_3);
            app.Lamp_3 = uilamp(app.Panel_3);
            app.Lamp_4 = uilamp(app.Panel_3);
            app.InstruccionesPanel = uipanel(app.Panel_4);
            app.InstruccionesPanel.Title = 'Instrucciones';
            app.TextArea = uitextarea(app.InstruccionesPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Bienvenido usuario(a)'; 'Los pasos a seguir son:'; ''; '1. Habilita la variable que vas a manejar, dando clic en el cuadro blanco que esta al lado del nombre de la variable correspondiente.'; ''; '2. Seleccione la variable a la que le va asignar la columna y la unidad, dando clic en el circulo blanco que esta al lado del nombre de la variable correspondiente.'; ''; '3. Indique dando clic a la columna o celda de la columna que desea asignarle el valor.'; ''; '4. Especifique la unidad que tiene la variable en el archivo.'; ''; '5. Repita el proceso para las demás variables.'; ''; '6. Guarde la configuración y puede salir.'};
            app.Panel = uipanel(app.Panel_4);
            app.Panel.Tooltip = {''};
            app.Panel.Title = 'Vista previa del documento (las primeras 7 filas)';
            app.Panel_2 = uipanel(app.Panel_4);
            app.UITable = uitable(app.Panel_4);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {''};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GuardarconfiguracinButton = uibutton(app.Panel_4, 'push');
            app.GuardarconfiguracinButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarconfiguracinButtonPushed, true);
            app.GuardarconfiguracinButton.Text = 'Guardar configuración';
            app.SalirButton = uibutton(app.Panel_4, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Enable = 'off';
            app.SalirButton.Text = 'Salir';
            app.HabilitePanel = uipanel(app.Panel_4);
            app.HabilitePanel.Title = 'Habilite';
            app.CheckBox = uicheckbox(app.HabilitePanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.HabilitePanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.HabilitePanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.HabilitePanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            % Show the figure after all components are created
            app.SettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingsUIFigure)
        end
    end
end