classdef Soporte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Support             matlab.ui.Figure
        IdiomaLanguageMenu  matlab.ui.container.Menu
        EnglishMenu         matlab.ui.container.Menu
        EspaolMenu          matlab.ui.container.Menu
        TabGroup            matlab.ui.container.TabGroup
        CreditosTab         matlab.ui.container.Tab
        Image               matlab.ui.control.Image
        JohnJairoGuerreroMorenoDiegoJulinRodrguezPatarroyoLabel  matlab.ui.control.Label
        Image2              matlab.ui.control.Image
        Image3              matlab.ui.control.Image
        Image4              matlab.ui.control.Image
        Image4_2            matlab.ui.control.Image
        Paso1Tab            matlab.ui.container.Tab
        TextArea_2          matlab.ui.control.TextArea
        FormulasTab         matlab.ui.container.Tab
        Image5              matlab.ui.control.Image
        Paso2Tab            matlab.ui.container.Tab
        TextArea_3          matlab.ui.control.TextArea
        Paso3Tab            matlab.ui.container.Tab
        TextArea_4          matlab.ui.control.TextArea
        FormulasTab_2       matlab.ui.container.Tab
        Image5_2            matlab.ui.control.Image
        Paso4Tab            matlab.ui.container.Tab
        TextArea_5          matlab.ui.control.TextArea
        ReferenciasTab      matlab.ui.container.Tab
        ReferenciasLabel    matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        Tree                matlab.ui.container.Tree
        CrditosNode         matlab.ui.container.TreeNode
        GlosarioNode        matlab.ui.container.TreeNode
        Paso1Node           matlab.ui.container.TreeNode
        DefinicionesNode    matlab.ui.container.TreeNode
        FrmulasNode         matlab.ui.container.TreeNode
        Paso2Node           matlab.ui.container.TreeNode
        Paso3Node           matlab.ui.container.TreeNode
        DefinicionesNode_2  matlab.ui.container.TreeNode
        FrmulasNode_2       matlab.ui.container.TreeNode
        Paso4Node           matlab.ui.container.TreeNode
        ReferenciasNode     matlab.ui.container.TreeNode
        Panel               matlab.ui.container.Panel
    end

%     AUTHORS
%
%     [1] John Jairo Guerrero Moreno
%     [2] Diego Julián Rodríguez Patarroyo
%     [3] Julián Andrés Salamanca Bernal
%
%     Universidad Distrital Francisco José de Caldas
%
%    Facultad de Ingeniería
%    Proyecto Curricular Ingeniería Eléctrica
%    [1,2] Laboratorio de Investigación de Fuentes Alternativas de Energía - LIFAE
%    [3] Grupo de Física e Informática - FISINFOR
%    Emails:
%    [1] jjguerrerom@correo.udistrital.edu.co,
%    [2] djrodriguezp@udistrital.edu.co,
%    [3] jasalamanca@udistrital.edu.co
%    December 1st, 2020


%COPYRIGHT
%
%     This file is part of SCWindDesign.
%     SCWindDesign is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
%
%     SCWindDesign is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
%
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    
    
    properties (Access = private)
        Menuapp;
        idioma;
    end
    
    methods (Access = public)
        
        function guardaridiomas(app, idioma) 
            
            app.Paso1Node.Text =  buscador(app, idioma, 'pone');
            app.Paso2Node.Text = "»»" + buscador(app, idioma, 'ptwo');
            app.Paso3Node.Text =  buscador(app, idioma, 'pthree');
            app.Paso4Node.Text = "»»" + buscador(app, idioma, 'pfour');
            app.CrditosNode.Text = "»»" + buscador(app, idioma, 'creditos');
            app.GlosarioNode.Text = buscador(app, idioma, 'glosario');
            app.TextArea_2.Value = buscador(app, idioma, 'pasounodef');
            app.TextArea_3.Value = buscador(app, idioma, 'pasodosdef');
            app.TextArea.Value = buscador(app, idioma, 'articulo') + newline + buscador(app, idioma, 'libro') + newline + buscador(app, idioma, 'rosita') + newline + buscador(app , idioma, 'densidadref') + newline + buscador(app, idioma, 'presionref')+ newline + buscador(app, idioma, 'temperatureref')+ newline + buscador(app, idioma, 'alturaref') + newline + buscador(app, idioma, 'histogramaref') + newline + buscador(app, idioma, 'sturges') + newline + buscador(app, idioma, 'aerogeneradorf');
            app.TextArea_4.Value = buscador(app, idioma, 'pasotresdef');
            app.TextArea_5.Value = buscador(app, idioma, 'aerogenerador');
            app.DefinicionesNode.Text = "»»"+ buscador(app, idioma, 'definiciones');
            app.DefinicionesNode_2.Text = "»»" + buscador(app, idioma, 'definiciones');
            app.FrmulasNode.Text = "»»" + buscador(app, idioma, 'formulas');
            app.FrmulasNode_2.Text = "»»" + buscador(app, idioma, 'formulas');
            app.ReferenciasNode.Text = "»»" + buscador(app, idioma, 'referencia');
            app.ReferenciasLabel.Text = buscador(app, idioma, 'referencia');
            if idioma == "Español"
                app.Image5.ImageSource = "formula1.svg";
                app.Image5_2.ImageSource = "formula2.svg";
            end
            if idioma == "English"
                app.Image5.ImageSource = "formula1eng.svg";
                app.Image5_2.ImageSource = "formula2eng.svg";
            end
        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, menuapp, idioma)
            app.idioma = idioma;
            app.Menuapp = menuapp;
            if idioma == "Español"
                app.EspaolMenu.Checked = "on";
                app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end
            app.Tree.SelectedNodes = app.CrditosNode;
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Español'
                    guardaridiomas(app, 'Español');
                    app.Image5.ImageSource = "formula1.svg";
                    app.Image5_2.ImageSource = "formula2.svg";
                case 'English'
                    guardaridiomas(app, 'English');
                    app.Image5.ImageSource = "formula1eng.svg";
                    app.Image5_2.ImageSource = "formula2eng.svg";
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.NodeData
                case 1
                    app.TabGroup.SelectedTab = app.CreditosTab;
                    app.Panel.Title = "";
                    
                case 4
                    app.TabGroup.SelectedTab = app.Paso1Tab;
                    app.Panel.Title = buscador(app, app.idioma, 'pone');
                case 5
                    app.TabGroup.SelectedTab = app.FormulasTab;
                    app.Panel.Title = buscador(app, app.idioma, 'formulas');
                case 6
                    app.TabGroup.SelectedTab = app.Paso2Tab;
                    app.Panel.Title = buscador(app, app.idioma, 'ptwo');
                case 8
                    app.TabGroup.SelectedTab = app.Paso3Tab;
                    app.Panel.Title = buscador(app, app.idioma, 'pthree');
                case 9
                    app.TabGroup.SelectedTab = app.FormulasTab_2;
                    app.Panel.Title = buscador(app, app.idioma, 'formulas');
                    
                case 10
                    app.TabGroup.SelectedTab = app.Paso4Tab;
                    app.Panel.Title = buscador(app, app.idioma, 'pfour');
                case 11
                    app.TabGroup.SelectedTab = app.ReferenciasTab;
                    app.Panel.Title = "";
                    app.ReferenciasLabel.Text = buscador(app, app.idioma, 'referencia');
                    
                    
            end
        end

        % Close request function: Support
        function SupportCloseRequest(app, event)
            app.Menuapp.SoporteButton.Enable = "on";
            delete(app)
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";
            app.Image5.ImageSource = "formula1eng.svg";
            app.Image5_2.ImageSource = "formula2eng.svg";
        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";
            app.Image5.ImageSource = "formula1.svg";
            app.Image5_2.ImageSource = "formula2.svg";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Support = uifigure('Visible', 'off');
            app.Support.Name = 'Soporte';
            app.Support.Icon = 'icono.png';
            app.Support.CloseRequestFcn = createCallbackFcn(app, @SupportCloseRequest, true);
            app.IdiomaLanguageMenu = uimenu(app.Support);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Separator = 'on';
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Separator = 'on';
            app.EspaolMenu.Text = 'Español';
            app.TabGroup = uitabgroup(app.Support);
            app.CreditosTab = uitab(app.TabGroup);
            app.CreditosTab.Title = 'Creditos';
            app.Image = uiimage(app.CreditosTab);
            app.Image.ImageSource = 'iconogrande.svg';
            app.JohnJairoGuerreroMorenoDiegoJulinRodrguezPatarroyoLabel = uilabel(app.CreditosTab);
            app.JohnJairoGuerreroMorenoDiegoJulinRodrguezPatarroyoLabel.Text = {'WIND DESIGN 1.0'; 'Autores/Authors :'; ''; 'John Jairo Guerrero Moreno '; 'Diego Julián Rodríguez Patarroyo '; 'Julián Andrés Salamanca Bernal'; ''; 'Universidad Distrital Francisco José de Caldas'; 'Ingeniería eléctrica /Electric engineering'; 'LIFAE'; 'FISINFOR'; ''; '2020'; ''};
            app.Image2 = uiimage(app.CreditosTab);
            app.Image2.ImageSource = 'udistrital.png';
            app.Image3 = uiimage(app.CreditosTab);
            app.Image3.ImageSource = 'lifae.jpg';
            app.Image4 = uiimage(app.CreditosTab);
            app.Image4.ImageSource = 'fisinfor.jpg';
            app.Image4_2 = uiimage(app.CreditosTab);
            app.Image4_2.ImageSource = 'ie.png';
            app.Paso1Tab = uitab(app.TabGroup);
            app.Paso1Tab.Title = 'Paso 1';
            app.TextArea_2 = uitextarea(app.Paso1Tab);
            app.TextArea_2.Editable = 'off';
            app.FormulasTab = uitab(app.TabGroup);
            app.FormulasTab.Title = 'Formulas';
            app.Image5 = uiimage(app.FormulasTab);
            app.Image5.ImageSource = 'formula1.svg';
            app.Paso2Tab = uitab(app.TabGroup);
            app.Paso2Tab.Title = 'Paso 2';
            app.TextArea_3 = uitextarea(app.Paso2Tab);
            app.TextArea_3.Editable = 'off';
            app.Paso3Tab = uitab(app.TabGroup);
            app.Paso3Tab.Title = 'Paso 3';
            app.TextArea_4 = uitextarea(app.Paso3Tab);
            app.TextArea_4.Editable = 'off';
            app.FormulasTab_2 = uitab(app.TabGroup);
            app.FormulasTab_2.Title = 'Formulas';
            app.Image5_2 = uiimage(app.FormulasTab_2);
            app.Image5_2.ImageSource = 'formula2.svg';
            app.Paso4Tab = uitab(app.TabGroup);
            app.Paso4Tab.Title = 'Paso 4';
            app.TextArea_5 = uitextarea(app.Paso4Tab);
            app.TextArea_5.Editable = 'off';
            app.ReferenciasTab = uitab(app.TabGroup);
            app.ReferenciasTab.Title = 'Referencias';
            app.ReferenciasLabel = uilabel(app.ReferenciasTab);
            app.ReferenciasLabel.Text = 'Referencias';
            app.TextArea = uitextarea(app.ReferenciasTab);
            app.Tree = uitree(app.Support);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.CrditosNode = uitreenode(app.Tree);
            app.CrditosNode.NodeData = 1;
            app.CrditosNode.Icon = 'iconogrande.svg';
            app.CrditosNode.Text = 'Créditos';
            app.GlosarioNode = uitreenode(app.Tree);
            app.GlosarioNode.NodeData = 2;
            app.GlosarioNode.Icon = 'instrucciones1.svg';
            app.GlosarioNode.Text = 'Glosario';
            app.Paso1Node = uitreenode(app.GlosarioNode);
            app.Paso1Node.NodeData = 3;
            app.Paso1Node.Text = 'Paso 1';
            app.DefinicionesNode = uitreenode(app.Paso1Node);
            app.DefinicionesNode.NodeData = 4;
            app.DefinicionesNode.Text = 'Definiciones';
            app.FrmulasNode = uitreenode(app.Paso1Node);
            app.FrmulasNode.NodeData = 5;
            app.FrmulasNode.Text = 'Fórmulas';
            app.Paso2Node = uitreenode(app.GlosarioNode);
            app.Paso2Node.NodeData = 6;
            app.Paso2Node.Text = 'Paso 2';
            app.Paso3Node = uitreenode(app.GlosarioNode);
            app.Paso3Node.NodeData = 7;
            app.Paso3Node.Text = 'Paso 3';
            app.DefinicionesNode_2 = uitreenode(app.Paso3Node);
            app.DefinicionesNode_2.NodeData = 8;
            app.DefinicionesNode_2.Text = 'Definiciones';
            app.FrmulasNode_2 = uitreenode(app.Paso3Node);
            app.FrmulasNode_2.NodeData = 9;
            app.FrmulasNode_2.Text = 'Fórmulas';
            app.Paso4Node = uitreenode(app.GlosarioNode);
            app.Paso4Node.NodeData = 10;
            app.Paso4Node.Text = 'Paso 4';
            app.ReferenciasNode = uitreenode(app.Tree);
            app.ReferenciasNode.NodeData = 11;
            app.ReferenciasNode.Text = 'Referencias';
            app.Panel = uipanel(app.Support);
            % Show the figure after all components are created
            app.Support.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Soporte(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Support)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Support)
        end
    end
end